classdef HoloApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoLLoAppUIFigure                matlab.ui.Figure
        HelperPanel                     matlab.ui.container.Panel
        BackLayout                      matlab.ui.container.GridLayout
        ApplicationStateBar             matlab.ui.container.GridLayout
        ApplicationStateLabel           matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        SideLayout                      matlab.ui.container.GridLayout
        LogoNavLayout                   matlab.ui.container.GridLayout
        NavigationLayout                matlab.ui.container.GridLayout
        GridLayout                      matlab.ui.container.GridLayout
        SaveSessionButton               matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.StateButton
        FocusButton                     matlab.ui.control.StateButton
        ShiftButton                     matlab.ui.control.StateButton
        ReconstructionButton            matlab.ui.control.StateButton
        ExportResultsButton             matlab.ui.control.StateButton
        GPUProcessingCheckBox           matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        MainLayout                      matlab.ui.container.GridLayout
        NavigationTab                   matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        LoadDataLayout                  matlab.ui.container.GridLayout
        LoadDataDisplayLayout           matlab.ui.container.GridLayout
        LoadDataGraphSettingsLayout     matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        LoadDataRangeStartEditField     matlab.ui.control.EditField
        ValuerangeLabel                 matlab.ui.control.Label
        LoadDataRangeEndEditField       matlab.ui.control.EditField
        LoadDataDisplayButtonsLayout    matlab.ui.container.GridLayout
        LoadDataPreviousButton          matlab.ui.control.Button
        LoadDataNextButton              matlab.ui.control.Button
        LoadDataUIAxes                  matlab.ui.control.UIAxes
        LoadDataControlLayout           matlab.ui.container.GridLayout
        ModeDataLayout                  matlab.ui.container.GridLayout
        ModeTabGroup                    matlab.ui.container.TabGroup
        MultiwavelenghtTab              matlab.ui.container.Tab
        MultiwavelengthLayout           matlab.ui.container.GridLayout
        PhotoWavelengthTable            matlab.ui.control.Table
        MultiheightTab                  matlab.ui.container.Tab
        MultiheightLayout               matlab.ui.container.GridLayout
        PhotoHeightTable                matlab.ui.control.Table
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        ModeLabel                       matlab.ui.control.Label
        DefaultValuesLayout             matlab.ui.container.GridLayout
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightmLabel                    matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        WavelengthmLabel                matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.NumericEditField
        PixelSizemLabel                 matlab.ui.control.Label
        DefaultvaluesLabel              matlab.ui.control.Label
        LoadDataFileListBoxLayout       matlab.ui.container.GridLayout
        LoadDataFilesListBox            matlab.ui.control.ListBox
        HologramsLabel                  matlab.ui.control.Label
        LoadHologramsButton             matlab.ui.control.Button
        FocusTab                        matlab.ui.container.Tab
        FocusLayout                     matlab.ui.container.GridLayout
        FocusDisplayLayout              matlab.ui.container.GridLayout
        FocusGraphSettingsLayout        matlab.ui.container.GridLayout
        FocusRangeEndEditField          matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        FocusRangeStartEditField        matlab.ui.control.EditField
        FocusRangeLabel                 matlab.ui.control.Label
        FocusDisplayButtonsLayout       matlab.ui.container.GridLayout
        FocusAmplitudeButton            matlab.ui.control.StateButton
        FocusPhaseButton                matlab.ui.control.StateButton
        FocusPreviousButton             matlab.ui.control.Button
        FocusNextButton                 matlab.ui.control.Button
        FocusUIAxes                     matlab.ui.control.UIAxes
        FocusControlLayout              matlab.ui.container.GridLayout
        FocusFilesPanel                 matlab.ui.container.Panel
        FocusFileListBoxLayout          matlab.ui.container.GridLayout
        FocusFilesListBox               matlab.ui.control.ListBox
        HologramsLabel_2                matlab.ui.control.Label
        FocusingPanel                   matlab.ui.container.Panel
        AdjustFocusLayout               matlab.ui.container.GridLayout
        FocusHeightTable                matlab.ui.control.Table
        PassforwardCheckboxLayout       matlab.ui.container.GridLayout
        PassforwardCheckBox             matlab.ui.control.CheckBox
        ShowfocuscurveCheckboxLayout    matlab.ui.container.GridLayout
        ShowfocuscurveCheckBox          matlab.ui.control.CheckBox
        InspectROICheckboxLayout        matlab.ui.container.GridLayout
        InspectROICheckBox              matlab.ui.control.CheckBox
        SweepAutoFocusLayout            matlab.ui.container.GridLayout
        AutofocusButton                 matlab.ui.control.Button
        SweepStartEditField             matlab.ui.control.NumericEditField
        StartmLabel                     matlab.ui.control.Label
        DarkfocusLabel                  matlab.ui.control.Label
        SweepRangeLabel                 matlab.ui.control.Label
        SweepRangeEditField             matlab.ui.control.NumericEditField
        SweepStepLabel                  matlab.ui.control.Label
        SweepStepEditField              matlab.ui.control.NumericEditField
        CurrentHeightControlLayout      matlab.ui.container.GridLayout
        CurrentHeightAddButton          matlab.ui.control.Button
        CurrentHeightSubtractButton     matlab.ui.control.Button
        CurrentHeightEditField          matlab.ui.control.NumericEditField
        CurrentHeightmLabel             matlab.ui.control.Label
        StepControlLayout               matlab.ui.container.GridLayout
        StepAddButton                   matlab.ui.control.Button
        StepSubtractButton              matlab.ui.control.Button
        StepEditField                   matlab.ui.control.NumericEditField
        StepLabel                       matlab.ui.control.Label
        AdjustFocusLabel                matlab.ui.control.Label
        ShiftTab                        matlab.ui.container.Tab
        ShiftLayout                     matlab.ui.container.GridLayout
        ShiftDisplayLayout              matlab.ui.container.GridLayout
        ShiftGrahpSettingsLayout        matlab.ui.container.GridLayout
        ShiftRangeEndEditField          matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        ShiftRangeStartEditField        matlab.ui.control.EditField
        ShiftRangeLabel                 matlab.ui.control.Label
        GotoLabel                       matlab.ui.control.Label
        GoToBlue                        matlab.ui.control.Button
        GoToGreen                       matlab.ui.control.Button
        GoToRed                         matlab.ui.control.Button
        ShiftDisplayButtonsLayout       matlab.ui.container.GridLayout
        ShiftAmplitudeButton            matlab.ui.control.StateButton
        ShiftPhaseButton                matlab.ui.control.StateButton
        ShiftPreviousButton             matlab.ui.control.Button
        ShiftNextButton                 matlab.ui.control.Button
        ShiftUIAxes                     matlab.ui.control.UIAxes
        ShiftControlLayout              matlab.ui.container.GridLayout
        ShiftFileListBoxLayout          matlab.ui.container.GridLayout
        ShiftFilesListBox               matlab.ui.control.ListBox
        FilesListBox_3Label_2           matlab.ui.control.Label
        ShiftHologramTypeDropDown       matlab.ui.control.DropDown
        CorrectShiftLayout              matlab.ui.container.GridLayout
        CropAndPadWithPaddingLayout     matlab.ui.container.GridLayout
        CropandPadLabel                 matlab.ui.control.Label
        CropAndPadLayerNumberLayout     matlab.ui.container.GridLayout
        LayerNumberEditField            matlab.ui.control.NumericEditField
        LayerNumberEditFieldLabel       matlab.ui.control.Label
        CropAndPadButtonsLayout         matlab.ui.container.GridLayout
        CropAndPadResetButton           matlab.ui.control.Button
        CropAndPadTopButton             matlab.ui.control.StateButton
        CropAndPadRightButton           matlab.ui.control.StateButton
        CropAndPadBottomButton          matlab.ui.control.StateButton
        CropAndPadLeftButton            matlab.ui.control.StateButton
        CorrectShiftLabel               matlab.ui.control.Label
        CorrectionModeDropDownLayout    matlab.ui.container.GridLayout
        CorrectionModeDropDown          matlab.ui.control.DropDown
        AutocorrectButton               matlab.ui.control.Button
        ManualcorrectButton             matlab.ui.control.Button
        XYTable                         matlab.ui.control.Table
        ReconstructionTab               matlab.ui.container.Tab
        ReconstructionLayout            matlab.ui.container.GridLayout
        ReconstructionDisplayLayout     matlab.ui.container.GridLayout
        ReconstructionGrahpSettingsLayout  matlab.ui.container.GridLayout
        ReconstructionRangeEndEditField  matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        ReconstructionRangeStartEditField  matlab.ui.control.EditField
        ReconstructionRangeLabel        matlab.ui.control.Label
        ReconstructionDisplayButtonsLayout  matlab.ui.container.GridLayout
        ReconstructionDarkFieldButton   matlab.ui.control.StateButton
        ReconstructionAmplitudeButton   matlab.ui.control.StateButton
        ReconstructionPhaseButton       matlab.ui.control.StateButton
        ReconstructionPreviousButton    matlab.ui.control.Button
        ReconstructionNextButton        matlab.ui.control.Button
        ReconstructionUIAxes            matlab.ui.control.UIAxes
        ReconstructionControlLayout     matlab.ui.container.GridLayout
        ReconstructionHologramsPanel    matlab.ui.container.Panel
        ReconstructionHologramsListBoxLayout  matlab.ui.container.GridLayout
        ReconstructionHologramsListBox  matlab.ui.control.ListBox
        FilesListBox_3Label_3           matlab.ui.control.Label
        ReconstructionSettingsLayout    matlab.ui.container.GridLayout
        PhaseMultiplierLayout           matlab.ui.container.GridLayout
        PhaseMultiplierEditField        matlab.ui.control.EditField
        PhaseMultiplierLabel            matlab.ui.control.Label
        ReconstructionsettingsLabel     matlab.ui.control.Label
        ReconstructionParametersLayout  matlab.ui.container.GridLayout
        SigmaEditField                  matlab.ui.control.NumericEditField
        IterationNumberEditField        matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        StartReconstruction             matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        ResultsLayout                   matlab.ui.container.GridLayout
        SaveSelectorLayout              matlab.ui.container.GridLayout
        AvailableHologramsListBox       matlab.ui.control.ListBox
        ResultsControlButtonsLayout     matlab.ui.container.GridLayout
        MoveAllLeft                     matlab.ui.control.Button
        MoveSingleLeft                  matlab.ui.control.Button
        MoveSingleRight                 matlab.ui.control.Button
        MoveAllRight                    matlab.ui.control.Button
        ExportedHologramsListBoxLabel   matlab.ui.control.Label
        ExportedHologramsListBox        matlab.ui.control.ListBox
        AvailableHologramsListBoxLabel  matlab.ui.control.Label
        SaveSettingsLayout              matlab.ui.container.GridLayout
        ExportSettingsLayout            matlab.ui.container.GridLayout
        ExportreconstructiondataCheckBox  matlab.ui.control.CheckBox
        FormatContolLayout              matlab.ui.container.GridLayout
        DeleteFormatButton              matlab.ui.control.Button
        AddFormatButton                 matlab.ui.control.Button
        FormatDropDown                  matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        DropDownLayout_3                matlab.ui.container.GridLayout
        ExportedFormatsEditField        matlab.ui.control.EditField
        ExportedFormatsEditFieldLabel   matlab.ui.control.Label
        ExportModeDropDownLayout        matlab.ui.container.GridLayout
        ExportModeDropDown              matlab.ui.control.DropDown
        ExportModeDropDownLabel         matlab.ui.control.Label
        ReconstructionPhaseMultiplierLayout  matlab.ui.container.GridLayout
        ReconstructionPhaseMultiplierEditField  matlab.ui.control.EditField
        ReconstructionPhaseMultiplierEditFieldLabel  matlab.ui.control.Label
        ExportDarkFieldReconstructionCheckBox  matlab.ui.control.CheckBox
        PhaseValueRangeLayout           matlab.ui.container.GridLayout
        PhaseStartValue                 matlab.ui.control.EditField
        PhaseValueRangeEditFieldLabel   matlab.ui.control.Label
        PhaseSeparator                  matlab.ui.control.Label
        PhaseEndValue                   matlab.ui.control.EditField
        AmplitudeValueRangeLayout       matlab.ui.container.GridLayout
        AmplitudeStartValue             matlab.ui.control.EditField
        AmplitudeSeparator              matlab.ui.control.Label
        AmplitudeEndValue               matlab.ui.control.EditField
        AmplitudeValueRangeLabel_3      matlab.ui.control.Label
        ExportsettingsLabel             matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentHologramIndex                (1,1) uint16                     = 1        % index of selected hologram
        UsedHeights                         (1,:) double                     = []       % recently used hologram distances
        IsShiftManualCorrectionOn                                            = false    % manual shift correction flag
        FocusROIRectanglePosition                                            = 0        %4

        %Derrived holograms
        HologramsPostPropagation            (:,:,:)                          = []
        CorrectedReference                  (:,:,:)                          = []
        CorrectedHolograms                  (:,:,:)                          = []
        FinalReconstructions                (:,:,:)                          = []       %8
        
        %Derrived data
        HologramsPostPropagationNames       cell                             = {}
        CorrectedReferenceNames             cell                             = {}
        CorrectedHologramsNames             cell                             = {}
        FinalReconstructionsNames           cell                             = {}
        CorrectedPropagationDistances       (1,:) double                     = []       %13
        
        %User input
        ReconstructionSigmas                (1,:) double {mustBeNonnegative} = []
        ReconstructionIterationNumber       (1,:)                            = []
        DeterminedPropagationDistances      (1,:) double                     = []
        AmplitudeDisplayRange               (1,2) double                     = [0, 255]
        PhaseDisplayRange                   (1,2) double                     = [-3.14, 3.14]
        ManualCorrectionXY                   cell                            = {}
        CorrectionTForms                                                     = []
        GPUProcessingStatus                                                  = 0
        CropAndPadVector                    (1,4)                            = [0, 0, 0, 0]
        QCIModel                            QCI_Model                        = QCI_Model()   %23    % Model used for hologram transforms
        HoloPrepHandle
        ClosePopupHandle
        FocusROIRectangleObject             images.roi.Rectangle                                  % Handle to the actual ROI object
        AxisZoomListener
    end
    
    properties (Access = public)
        Files
        PopupStatus

        %Derrived holograms
        HologramsRaw            (:,:,:)                          = []

        %Derrived data
        HologramsRawNames cell                                   = {}
        SessionStartDateTime
    end
    
    methods (Access = private)
        %% Model
        function LoadHologramsToQCIModel(app)
            if(~isempty(app.HologramsRaw))
                % Getting default lambdas, heights, and camera pixel sizes
                imageNumber = length(app.HologramsRawNames);
                wavelengths = ones(1,imageNumber)*app.WavelengthEditField.Value;
                heights = ones(1,imageNumber)*app.HeightEditField.Value;
                pixelSize = app.PixelSizeEditField.Value;
                app.UsedHeights = zeros(1,imageNumber);

                % Initializing the model
                
                if(app.GPUProcessingStatus)
                     app.QCIModel = QCI_Model_GPU(app.HologramsRawNames, app.HologramsRaw, wavelengths, heights, pixelSize);
                else
                    app.QCIModel = QCI_Model(app.HologramsRawNames, app.HologramsRaw, wavelengths, heights, pixelSize);
                end

                app.HologramsRaw = app.QCIModel.getHolograms(0);
                app.HologramsPostPropagation = app.HologramsRaw;
            end
        end

        %% View
        function HandleHologramChange(app, newFileIndex)
            if(isempty(app.HologramsRaw))
                return
            end

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                hologramCount = size(app.HologramsRaw,3);
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                hologramCount = size(app.HologramsPostPropagation,3);
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                hologramCount = size(app.HologramsRaw,3);
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                hologramCount = size(app.FinalReconstructions, 3);
            end

            if(hologramCount == 1)
                app.CurrentHologramIndex = 1;
            elseif (newFileIndex < 1)
                app.CurrentHologramIndex = hologramCount;
            elseif(newFileIndex == hologramCount)
                app.CurrentHologramIndex = hologramCount;
            else
                app.CurrentHologramIndex = mod(newFileIndex, hologramCount);
            end

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;

                app.LoadDataRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                app.LoadDataRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

                image = app.HologramsRaw(:,:, app.CurrentHologramIndex);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.LoadDataUIAxes);

                if(app.LoadDataUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.LoadDataUIAxes.YLim(2) > size(app.HologramsRaw,1))
                    app.LoadDataUIAxes.XLim(2) = size(app.HologramsRaw,2);
                    app.LoadDataUIAxes.YLim(2) = size(app.HologramsRaw,1);
                end
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                app.FocusFilesListBox.Value = app.CurrentHologramIndex;
                
                all=0;
                heights = app.QCIModel.getPropagationDistances(all);
                app.CurrentHeightEditField.Value=heights(app.CurrentHologramIndex);
                app.SweepStartEditField.Value = app.CurrentHeightEditField.Value;

                app.DisplayFocus();
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                app.LoadShiftListBox();
                app.ShiftFilesListBox.Value = app.CurrentHologramIndex;

                app.DisplayShift(app.IsShiftManualCorrectionOn);
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                app.LoadReconstructionListBox();

                if(~isempty(app.ReconstructionHologramsListBox.Value))
                    app.ReconstructionHologramsListBox.Value = app.CurrentHologramIndex;
                end

                app.DisplayReconstruction();
            end
        end
        
        function HandleModeChange(app, newModeValue)
            switch newModeValue
                case 'Single hologram' %SingleHologram
                    app.ModeTabGroup.Visible = "off";
                case 'Multi-wavelenght' %Multi-wavelength
                    app.ModeTabGroup.SelectedTab = app.MultiwavelenghtTab;
                    app.CorrectionModeDropDown.Value = "OnlyShift";
                    app.ModeTabGroup.Visible = "on";
                case 'Multi-height' %Multi-height
                    app.ModeTabGroup.SelectedTab = app.MultiheightTab;
                    app.CorrectionModeDropDown.Value = "ShiftAndScaling";
                    app.ModeTabGroup.Visible = "on";
                otherwise
                    return
            end
        end
        
        function LoadModeTableData(app)
            all = 0;
            heights = app.QCIModel.getPropagationDistances(all);
            wavelengths = app.QCIModel.getWavelengths(all);
            names = app.QCIModel.getNames(all);
            focusNames = app.HologramsPostPropagationNames;
            focusNames = string(focusNames);

            app.PhotoHeightTable.Data =[names.' heights.'];
            app.FocusHeightTable.Data =[focusNames.' heights.'];
            app.PhotoWavelengthTable.Data = [names.' wavelengths.'];
        end

        function LoadXYTableData(app)
            pointsStruct = app.ManualCorrectionXY{app.CurrentHologramIndex};

            for pointIndex = 1:size(pointsStruct,2)
                x(pointIndex) = pointsStruct{pointIndex}(1);
                y(pointIndex) = pointsStruct{pointIndex}(2);
            end

            app.XYTable.Data = [x.' y.'];
        end

        function DisplayFocus(app)
            % display app state
            app.ApplicationStateLabel.Text = "Focusing...";

            all = 0;
            heights = app.QCIModel.getPropagationDistances(all);
            heights(app.CurrentHologramIndex) = app.CurrentHeightEditField.Value;

            if(app.UsedHeights(app.CurrentHologramIndex) == heights(app.CurrentHologramIndex))
                if(heights(app.CurrentHologramIndex) == 0)
                    image = app.HologramsRaw(:,:,app.CurrentHologramIndex);
                else
                    image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
                end
            else
                app.QCIModel.setPropagationDistances(heights);
                app.UsedHeights(app.CurrentHologramIndex) = heights(app.CurrentHologramIndex);
                app.HologramsPostPropagation(:,:,app.CurrentHologramIndex) = app.QCIModel.propagate(app.CurrentHologramIndex);
                image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
            end

            if(app.FocusAmplitudeButton.Value == 1)
                app.FocusRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                app.FocusRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

                image = abs(image);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.FocusUIAxes);
            else
                app.FocusRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                app.FocusRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));

                image = angle(image);
                imshow(image, app.PhaseDisplayRange, "Parent", app.FocusUIAxes);
            end
            
            if(app.FocusUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.FocusUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.FocusUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.FocusUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end

            if(app.InspectROICheckBox.Value)
                app.ApplicationStateLabel.Text = "Selecting ROI...";
                
                % --- 1. Create the Rectangle (Your existing code) ---
                if(app.FocusROIRectanglePosition ~= 0)
                    % Note: We assign the output to 'rectangle' so we can reference it
                    rectangle = drawrectangle(app.FocusUIAxes, 'Color', 'b', "Position", app.FocusROIRectanglePosition);
                else
                    rectangle = drawrectangle(app.FocusUIAxes, 'Color', 'b');
                    rectangle.Position = round(rectangle.Position);
                    app.FocusROIRectanglePosition = rectangle.Position;
                end
                
                % --- 2. Store the Rectangle Handle ---
                % Important: You need to access this rectangle in the Zoom callback later.
                % If you don't have a property for it, assign it to the app handles now:
                app.FocusROIRectangleObject = rectangle; % Assuming you added this property as discussed
            
                % --- 3. Add Listeners ---
                % Listener 1: ROI Movement (Your existing code)
                addlistener(rectangle, 'ROIMoved', @app.FocusROIUpdateRectangleListener);
                
                % Listener 2: Zoom/Pan Protection (NEW)
                % We verify visibility whenever the X or Y limits change.
                app.AxisZoomListener = addlistener(app.FocusUIAxes, {'XLim', 'YLim'}, 'PostSet', @(src, event) app.CheckROIVisibility);
            
            else
                % --- Cleanup when Unchecked ---
                app.ApplicationStateLabel.Text = "ROI Inspection Off";
                
                % Delete the rectangle if it exists
                if isvalid(app.FocusROIRectangleObject)
                    delete(app.FocusROIRectangleObject);
                end
                
                % Delete the zoom listener so it stops checking
                if ~isempty(app.AxisZoomListener)
                    delete(app.AxisZoomListener);
                    app.AxisZoomListener = [];
                end
            end

            % display app state
            app.ApplicationStateLabel.Text = "Idle";
        end

        function DisplayShift(app, isManualShiftCorrectionOn)
           if(nargin<2)
               isManualShiftCorrectionOn = false;
           end

           if(isManualShiftCorrectionOn)
               app.DisplayShiftManualCorrection();
           else
               app.DisplayShiftDefault();
           end

            if(app.ShiftUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.ShiftUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.ShiftUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.ShiftUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end
        end

        function DisplayShiftDefault(app)
            app.ShiftAmplitudeButton.Enable = "on";
            app.ShiftPhaseButton.Enable = "on";
            
            if(app.ShiftAmplitudeButton.Value == 1)
                app.ShiftRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                app.ShiftRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
            else
                app.ShiftRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                app.ShiftRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));
            end

            switch(app.ShiftHologramTypeDropDown.Value)
                case 'Reconstruction' %Reference 
                    image = app.HologramsPostPropagation(:,:,app.CurrentHologramIndex);
                case 'Holograms' % Holograms
                    app.ShiftAmplitudeButton.Enable = "off";
                    app.ShiftPhaseButton.Enable = "off";

                    image = app.HologramsRaw(:,:, app.CurrentHologramIndex);

                    imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
                    return
                case 'Shifted Reconstruction' % Corrected Reference
                   if(~isempty(app.CorrectedReference))
                       image = app.CorrectedReference(:,:,app.CurrentHologramIndex); 
                   else
                       image = zeros(size(app.HologramsRaw(:,:,1)));
                   end 
                case 'Shifted Holograms' % Corrected Holograms
                   app.ShiftAmplitudeButton.Enable = "off";
                   app.ShiftPhaseButton.Enable = "off";

                   if(~isempty(app.CorrectedHolograms))
                       image = app.CorrectedHolograms(:,:,app.CurrentHologramIndex);

                       croppedComplexImage = app.cropAndPadImages(image, app.CropAndPadVector);
                       image = croppedComplexImage;
                   else
                       image = zeros(size(app.HologramsRaw(:,:,1)));
                   end

                   imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
                   return
            end

            app.DisplayShiftImage(image);
        end
        
        function DisplayShiftManualCorrection(app)
            if(app.CorrectionModeDropDown.Value == "ShiftAndScaling")
                if(isempty(app.ManualCorrectionXY))
                    referenceImage = app.HologramsPostPropagation(:,:, end);
        
                    app.DisplayShiftImage(referenceImage);
                    titletext = "\color{white}Reference: Mark 3 points";
                    title(app.ShiftUIAxes, titletext);
        
                    % Allow the user to mark 3 points on the reference image
                    points = cell(1,3);
                    points{1} = drawpoint(app.ShiftUIAxes, 'Color','r'); % User marks 3 points
                    points{2} = drawpoint(app.ShiftUIAxes, 'Color','g'); % User marks 3 points
                    points{3} = drawpoint(app.ShiftUIAxes, 'Color','b'); % User marks 3 points
                    
                    points{1}.Position = round(points{1}.Position);
                    points{2}.Position = round(points{2}.Position);
                    points{3}.Position = round(points{3}.Position);
    
                    app.ManualCorrectionXY = cell(1,size(app.HologramsRaw,3));
    
                    app.ManualCorrectionXY{end} = { [points{1}.Position(1), points{1}.Position(2)],...
                                                    [points{2}.Position(1), points{2}.Position(2)],...
                                                    [points{3}.Position(1), points{3}.Position(2)]};
        
                    correctedImages = app.HologramsPostPropagation(:,:, 1:end-1);
                    cla(app.ShiftUIAxes); % Clear current axes
    
                    for i = 1:size(correctedImages,3)
                        % Store the marked points for further processing (optional)
                        app.ManualCorrectionXY{i} =  app.ManualCorrectionXY{end};
                    
                        % Clear the second axes for the next image
                        cla(app.ShiftUIAxes); % Clear current axes
                    end
    
                    app.ShiftFilesListBox.Enable = "on";
                    app.ShiftPreviousButton.Enable = "on";
                    app.ShiftNextButton.Enable = "on";

                    app.GotoLabel.Visible = "on";
                    app.GoToRed.Visible = "on";
                    app.GoToGreen.Visible = "on";
                    app.GoToBlue.Visible = "on";
    
                    app.ManualcorrectButton.Text = "Finish correction";
                    app.CurrentHologramIndex = 1;
                    app.HandleHologramChange(app.CurrentHologramIndex);
                else
                    cla(app.ShiftUIAxes);
                    app.LoadXYTableData();
                    image = app.HologramsPostPropagation(:,:, app.CurrentHologramIndex);

                    titletext = "\color{white}" + sprintf('Image %d: Adjust Points', app.CurrentHologramIndex);
                    title(app.ShiftUIAxes, titletext);
                    
                    position1 = app.ManualCorrectionXY{app.CurrentHologramIndex}{1};
                    position2 = app.ManualCorrectionXY{app.CurrentHologramIndex}{2};
                    position3 = app.ManualCorrectionXY{app.CurrentHologramIndex}{3};
    
                    app.DisplayShiftImage(image);
    
                    newPoint1 = drawpoint(app.ShiftUIAxes, 'Color', 'r', 'Position', position1); % User marks 3 points
                    newPoint2 = drawpoint(app.ShiftUIAxes, 'Color', 'g', 'Position', position2); % User marks 3 points
                    newPoint3 = drawpoint(app.ShiftUIAxes, 'Color', 'b', 'Position', position3); % User marks 3 points
    
                    addlistener(newPoint1, 'ROIMoved', @app.UpdatePointListener);
                    addlistener(newPoint2, 'ROIMoved', @app.UpdatePointListener);
                    addlistener(newPoint3, 'ROIMoved', @app.UpdatePointListener);
                end
            elseif(app.CorrectionModeDropDown.Value == "OnlyShift")
                if(isempty(app.ManualCorrectionXY))
                    referenceImage = app.HologramsPostPropagation(:,:, end);
        
                    app.DisplayShiftImage(referenceImage);
                    titletext = "\color{white}Reference: Mark a point";
                    title(app.ShiftUIAxes, titletext);
        
                    % Allow the user to mark 3 points on the reference image
                    points = cell(1,1);
                    points{1} = drawpoint(app.ShiftUIAxes, 'Color','r'); % User marks 3 points
                    
                    points{1}.Position = round(points{1}.Position);
    
                    app.ManualCorrectionXY = cell(1,size(app.HologramsRaw,3));
    
                    app.ManualCorrectionXY{end} = { [points{1}.Position(1), points{1}.Position(2)] };
        
                    correctedImages = app.HologramsPostPropagation(:,:, 1:end-1);
                    cla(app.ShiftUIAxes); % Clear current axes
    
                    for i = 1:size(correctedImages,3)
                        % Second subplot: Changing Image
                        currentImage = correctedImages(:,:,i);
                        app.DisplayShiftImage(currentImage);

                        titletext = "\color{white}" + sprintf('Image %d: Mark a Point', i);
                        title(app.ShiftUIAxes, titletext);

                        points{1} = drawpoint(app.ShiftUIAxes, 'Color','r'); % User marks 3 points
 
                        points{1}.Position = round(points{1}.Position);
                    
                        % Store the marked points for further processing (optional)
                        app.ManualCorrectionXY{i} =  { [points{1}.Position(1), points{1}.Position(2)] };
                    
                        % Clear the second axes for the next image
                        cla(app.ShiftUIAxes); % Clear current axes
                    end
    
                    app.ShiftFilesListBox.Enable = "on";
                    app.ShiftPreviousButton.Enable = "on";
                    app.ShiftNextButton.Enable = "on";

                    app.GotoLabel.Visible = "on";
                    app.GoToRed.Visible = "on";
    
                    app.ManualcorrectButton.Text = "Finish correction";
                    app.CurrentHologramIndex = 1;
                    app.HandleHologramChange(app.CurrentHologramIndex);
                else
                    cla(app.ShiftUIAxes);
                    app.LoadXYTableData();
                    image = app.HologramsPostPropagation(:,:, app.CurrentHologramIndex);

                    titletext = "\color{white}" + sprintf('Image %d: Adjust Points', app.CurrentHologramIndex);
                    title(app.ShiftUIAxes, titletext);
    
                    position1 = app.ManualCorrectionXY{app.CurrentHologramIndex}{1};
    
                    app.DisplayShiftImage(image);
    
                    newPoint1 = drawpoint(app.ShiftUIAxes, 'Color', 'r', 'Position', position1); % User marks 3 points

                    addlistener(newPoint1, 'ROIMoved', @app.UpdatePointListener);
                end
            end
        end

        function DisplayShiftImage(app, image)
            croppedComplexImage = app.cropAndPadImages(image, app.CropAndPadVector);
            image = croppedComplexImage;

            if(app.ShiftAmplitudeButton.Value == 1)
                image = abs(image);
                imshow(uint8(image), app.AmplitudeDisplayRange, "Parent", app.ShiftUIAxes);
            else
                image = angle(image);
                imshow(image, app.PhaseDisplayRange, "Parent", app.ShiftUIAxes);
            end
        end

        function DisplayReconstruction(app)
            if(app.ReconstructionAmplitudeButton.Value == 1 || app.ReconstructionDarkFieldButton.Value == 1)
                app.ReconstructionRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                app.ReconstructionRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
            elseif(app.ReconstructionPhaseButton.Value == 1)
                app.ReconstructionRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                app.ReconstructionRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));
            end

            if(~isempty(app.FinalReconstructions) && ~(size(app.FinalReconstructions, 1) == 0))
                complexImage = app.FinalReconstructions(:,:,app.CurrentHologramIndex);
            else
                complexImage = zeros(size(app.HologramsRaw(:,:,1)));
            end

            if(app.ReconstructionAmplitudeButton.Value == 1)
                imageAmplitude = abs(complexImage);
                imshow(imageAmplitude, app.AmplitudeDisplayRange, "Parent", app.ReconstructionUIAxes);
            elseif(app.ReconstructionPhaseButton.Value == 1)
                imagePhase = angle(complexImage) * str2double(app.PhaseMultiplierEditField.Value);
                imshow(imagePhase, app.PhaseDisplayRange, "Parent", app.ReconstructionUIAxes);
            elseif(app.ReconstructionDarkFieldButton.Value == 1)
                RealImagePart = real(complexImage);
                ImaginaryImagePart = imag(complexImage);
                RealImagePartGaussian = imgaussfilt(RealImagePart,30);
                ImaginaryImagePartGaussian = imgaussfilt(ImaginaryImagePart,30);
                GaussianImage = RealImagePartGaussian + 1i.*ImaginaryImagePartGaussian;
                darkFieldImage = abs(complexImage-GaussianImage);
                imshow(darkFieldImage, app.AmplitudeDisplayRange, "Parent", app.ReconstructionUIAxes);
            end

            if(app.ReconstructionUIAxes.XLim(2) > size(app.HologramsRaw,2) && app.ReconstructionUIAxes.YLim(2) > size(app.HologramsRaw,1))
                app.ReconstructionUIAxes.XLim(2) = size(app.HologramsRaw,2);
                app.ReconstructionUIAxes.YLim(2) = size(app.HologramsRaw,1);
            end
        end

        function result = EvaluateMathExpression(app,expression)
            % Replace 'pi' with its numerical value
            expression = strrep(expression, 'pi', num2str(pi));

            % Replace 'e' with its numerical value
            expression = strrep(expression, 'e', num2str(exp(1)));
            
            % Replace ':' with '/' to allow division
            expression = strrep(expression, ':', '/');
            
            try
                result = eval(expression);
            catch
                error('Invalid mathematical expression: %s', expression);
            end
        end

        function LoadHologramsListBox(app)
            if(~isempty(app.HologramsRawNames))
                %Clearing Listbox data
                app.LoadDataFilesListBox.Items = {};
                app.FocusFilesListBox.Items = {};
                app.ShiftFilesListBox.Items = {};
                app.ReconstructionHologramsListBox.Items = {};
                app.ExportedHologramsListBox.Items = {};
                app.AvailableHologramsListBox.Items = {};

                app.LoadDataFilesListBox.ItemsData = [];
                app.FocusFilesListBox.ItemsData = [];
                app.ShiftFilesListBox.ItemsData = [];
                app.ReconstructionHologramsListBox.ItemsData = [];
                app.ExportedHologramsListBox.ItemsData = [];
                app.AvailableHologramsListBox.ItemsData = [];

                app.HologramsPostPropagationNames = {};
                app.CorrectedReferenceNames = {};
                app.CorrectedHologramsNames = {};
                app.FinalReconstructionsNames= {};
                
                for i = 1:length(app.HologramsRawNames)
                    %Names in the list box
                    fileName = app.HologramsRawNames{i}; 
                    FocusName = "AfterFocus" + fileName;
                    ShiftCorrectedReferenceName = "CorrectedAfterFocus" + fileName;
                    ShiftCorrectedHologramsName = "Corrected" + fileName;

                    app.HologramsPostPropagationNames = [app.HologramsPostPropagationNames, cellstr(FocusName)];
                    app.CorrectedReferenceNames = [app.CorrectedReferenceNames, cellstr(ShiftCorrectedReferenceName)];
                    app.CorrectedHologramsNames = [app.CorrectedHologramsNames, cellstr(ShiftCorrectedHologramsName)];
                end

                app.LoadDataFilesListBox.Items = app.HologramsRawNames;
                app.FocusFilesListBox.Items = app.HologramsPostPropagationNames;
                app.ShiftFilesListBox.Items = app.CorrectedReferenceNames;

                app.LoadDataFilesListBox.ItemsData = [1:length(app.HologramsRawNames)];
                app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
                app.FocusFilesListBox.ItemsData = [1:length(app.HologramsRawNames)];
                app.FocusFilesListBox.Value = app.CurrentHologramIndex;
                app.ShiftFilesListBox.ItemsData = [1:length(app.HologramsRawNames)];
                app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
            end
        end

        function LoadShiftListBox(app)
             app.ShiftFilesListBox.Items=cellstr(num2str([]));
             switch(app.ShiftHologramTypeDropDown.Value)
                 case 'Reconstruction' %Reference
                    app.ShiftFilesListBox.Items = app.HologramsPostPropagationNames;
                 case 'Holograms' % Holograms
                    app.ShiftFilesListBox.Items = app.HologramsRawNames;
                 case 'Shifted Reconstruction' % Corrected Reference
                   app.ShiftFilesListBox.Items = app.CorrectedReferenceNames;
                 case 'Shifted Holograms' % Corrected Holograms
                   app.ShiftFilesListBox.Items = app.CorrectedHologramsNames;
             end

             app.LoadDataFilesListBox.ItemsData = [1:length(app.HologramsRawNames)];
             app.LoadDataFilesListBox.Value = app.CurrentHologramIndex;
        end

        function LoadReconstructionListBox(app)
            hologramCount = length(app.FinalReconstructionsNames);

            if(hologramCount == 0 || size(app.FinalReconstructions,1) == 0)
                return
            end

            app.ReconstructionHologramsListBox.Items = app.FinalReconstructionsNames;
            app.ReconstructionHologramsListBox.ItemsData = 1:hologramCount;
            app.ReconstructionHologramsListBox.Value = 1;
        end

        function LoadExportResultsAvailableHologramsListBox(app)
            app.AvailableHologramsListBox.Items = [app.HologramsRawNames app.HologramsPostPropagationNames app.CorrectedReferenceNames app.CorrectedHologramsNames app.FinalReconstructionsNames];
            
            hologramCounts = [length(app.HologramsRawNames) length(app.HologramsPostPropagationNames) length(app.CorrectedReferenceNames) length(app.CorrectedHologramsNames) length(app.FinalReconstructionsNames) ];

            listBoxIndexes = [];
            for hologramType = 1:length(hologramCounts)
                singleTypeIndexes = (1:hologramCounts(hologramType)) + (hologramType-1)*1000;
                listBoxIndexes = [listBoxIndexes singleTypeIndexes];
            end

            app.AvailableHologramsListBox.ItemsData = listBoxIndexes;
            
            if(~isempty(app.AvailableHologramsListBox.Value))
                app.AvailableHologramsListBox.Value = 1;
            end
        end

        function LoadExportResultsExportedHologramsListBox(app)
            app.ExportedHologramsListBox.Items = [app.HologramsRawNames app.HologramsPostPropagationNames app.CorrectedReferenceNames app.CorrectedHologramsNames app.FinalReconstructionsNames];

            hologramCounts = [length(app.HologramsRawNames) length(app.HologramsPostPropagationNames) length(app.CorrectedReferenceNames) length(app.CorrectedHologramsNames) length(app.FinalReconstructionsNames) ];

            listBoxIndexes = [];
            for hologramType = 1:length(hologramCounts)
                    singleTypeIndexes = (1:hologramCounts(hologramType)) + (hologramType-1)*1000;
                    listBoxIndexes = [listBoxIndexes singleTypeIndexes];
            end

            app.ExportedHologramsListBox.ItemsData = listBoxIndexes;
            
            if(~isempty(app.ExportedHologramsListBox.Value))
                app.ExportedHologramsListBox.Value = 1;
            end
        end

        function  [sortedItems, sortedItemsData] = SortListBoxByRisingValue(app, listbox)
            [sortedData,sortedIndexes] = sort(listbox.ItemsData);
            sortedItems = listbox.Items(sortedIndexes);
            sortedItemsData= sortedData;
        end
        
        function ExportHolograms(app, path)
            hologramCodes = app.ExportedHologramsListBox.ItemsData;
            hologramCount = numel(hologramCodes);

            hologramsRaw = 0;
            hologramsPostPropagation = 1;
            correctedReferenceHolograms = 2;
            correctedHolograms = 3;
            finalReconstructions = 4;
            
            for index = 1:hologramCount
                hologramType = round(hologramCodes(index)/1000);
                hologramIndex = mod(hologramCodes(index), 1000);

                switch(hologramType)
                    case hologramsRaw
                        hologram = app.HologramsRaw(:,:,hologramIndex);
                        hologramName = app.HologramsRawNames{hologramIndex};
                    case hologramsPostPropagation
                        hologram = app.HologramsPostPropagation(:,:,hologramIndex);
                        hologramName = app.HologramsPostPropagationNames{hologramIndex};
                    case correctedReferenceHolograms
                        hologram = app.CorrectedReference(:,:,hologramIndex);
                        hologramName = app.CorrectedReferenceNames{hologramIndex};
                    case correctedHolograms
                        hologram = app.CorrectedHolograms(:,:,hologramIndex);
                        hologramName = app.CorrectedHologramsNames{hologramIndex};
                    case finalReconstructions
                        hologram = app.FinalReconstructions(:,:,hologramIndex);
                        hologramName = app.FinalReconstructionsNames{hologramIndex};
                end
                
                formatString = app.ExportedFormatsEditField.Value;     
                
                if(contains(formatString,".tif"))
                    format = ".tif";
                    amplitudeStartValue = str2double(app.AmplitudeStartValue.Value);
                    amplitudeEndValue = str2double(app.AmplitudeEndValue.Value);
                    phaseStartValue = str2double(app.PhaseStartValue.Value);
                    phaseEndValue = str2double(app.PhaseEndValue.Value);

                    amplitudeNameString = "Amplitude_" + amplitudeStartValue + "_" + amplitudeEndValue;
                    darkFieldNameString = "DarkField_" + amplitudeStartValue + "_" + amplitudeEndValue;
                    phaseNameString = "Phase_" + phaseStartValue + "_" + phaseEndValue;

                    if(ismember(hologramType, [hologramsRaw correctedHolograms]))
                        hologramNameToSaveTif = strcat(path,"\", hologramName, amplitudeNameString, format);
                        hologram = uint8(hologram);
                        hologram = imadjust(hologram, [amplitudeStartValue/255, amplitudeEndValue/255]);
                        imwrite(hologram, hologramNameToSaveTif);
                    elseif(ismember(hologramType, [hologramsPostPropagation correctedReferenceHolograms finalReconstructions]))
                        hologramAmplitudeName = strcat(path, "\", hologramName, amplitudeNameString, format);
                        hologramReconstructionDarkFieldName = strcat(path, "\", hologramName, darkFieldNameString, format);
                        hologramPhaseName = strcat(path, "\", hologramName, phaseNameString, format);
                        
                        exportMode = app.ExportModeDropDown.Value;
                        
                        if(exportMode == "Amplitude") 
                            hologramAmplitude = uint8(abs(hologram));
                            hologramAmplitude = imadjust(hologramAmplitude, [amplitudeStartValue/255, amplitudeEndValue/255]);
                            imwrite(hologramAmplitude, hologramAmplitudeName);
                        elseif(exportMode == "Phase")
                            if(contains(hologramName,"Reconstruction"))
                                hologramPhase = angle(hologram)*str2double(app.ReconstructionPhaseMultiplierEditField.Value);
                            else
                                hologramPhase = angle(hologram);
                            end

                            hologramPhaseNormalized = (hologramPhase - phaseStartValue) / (phaseEndValue - phaseStartValue);
                            % Clip values to stay within [0,1]
                            hologramPhaseNormalized(hologramPhaseNormalized < 0) = 0;
                            hologramPhaseNormalized(hologramPhaseNormalized > 1) = 1;

                            hologramPhaseNormalized = imadjust(hologramPhaseNormalized, [0 1], [0 1]);
                          
                            imwrite(hologramPhaseNormalized,  hologramPhaseName);
                        elseif(exportMode == "Both")
                            hologramAmplitude = uint8(abs(hologram));

                            if(contains(hologramName,"Reconstruction"))
                                hologramPhase = angle(hologram)*str2double(app.ReconstructionPhaseMultiplierEditField.Value);
                            else
                                hologramPhase = angle(hologram);
                            end

                            hologramAmplitude = imadjust(hologramAmplitude, [amplitudeStartValue/255, amplitudeEndValue/255]);
                            
                            hologramPhaseNormalized = (hologramPhase - phaseStartValue) / (phaseEndValue - phaseStartValue);
                            % Clip values to stay within [0,1]
                            hologramPhaseNormalized(hologramPhaseNormalized < 0) = 0;
                            hologramPhaseNormalized(hologramPhaseNormalized > 1) = 1;

                            hologramPhaseNormalized = imadjust(hologramPhaseNormalized, [0 1], [0 1]);

                            imwrite(hologramAmplitude, hologramAmplitudeName);
                            imwrite(hologramPhaseNormalized, hologramPhaseName);
                        end

                        if(ismember(hologramType, finalReconstructions) && app.ExportDarkFieldReconstructionCheckBox.Value == 1)
                            realHologramPart = real(hologram);
                            imaginaryHologramPart = imag(hologram);

                            realImagePartGaussian = imgaussfilt(realHologramPart,30);
                            imaginaryImagePartGaussian = imgaussfilt(imaginaryHologramPart,30);

                            gaussianHologram = realImagePartGaussian + 1i.*imaginaryImagePartGaussian;

                            darkFieldHologram = abs(hologram-gaussianHologram);

                            darkFieldHologramNormalized = (darkFieldHologram - amplitudeStartValue) / (amplitudeEndValue - amplitudeStartValue);
                            % Clip values to stay within [0,1]
                            darkFieldHologramNormalized(darkFieldHologramNormalized < 0) = 0;
                            darkFieldHologramNormalized(darkFieldHologramNormalized > 1) = 1;

                            darkFieldHologramNormalized = imadjust(darkFieldHologramNormalized, [0 1], [0 1]);

                            imwrite(darkFieldHologramNormalized, hologramReconstructionDarkFieldName);
                        end     
                    end
                end

                if(contains(formatString,".fig"))
                    format = ".fig";
                    amplitudeStartValue = str2double(app.AmplitudeStartValue.Value);
                    amplitudeEndValue = str2double(app.AmplitudeEndValue.Value);
                    phaseStartValue = str2double(app.PhaseStartValue.Value);
                    phaseEndValue = str2double(app.PhaseEndValue.Value);

                    amplitudeNameString = "Amplitude_" + amplitudeStartValue + "_" + amplitudeEndValue;
                    darkFieldNameString = "DarkField_" + amplitudeStartValue + "_" + amplitudeEndValue;
                    phaseNameString = "Phase_" + phaseStartValue + "_" + phaseEndValue;

                    if(ismember(hologramType, [hologramsRaw correctedHolograms]))
                        hologramNameToSaveFig = strcat(path,"\", hologramName, amplitudeNameString, format);
                        hologram = uint8(hologram);
                        hologram = imadjust(hologram, [amplitudeStartValue/255, amplitudeEndValue/255]);
                        figure('Visible','off');
                        imshow(hologram);
                        set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                        savefig(hologramNameToSaveFig);
                        close
                    elseif(ismember(hologramType, [hologramsPostPropagation correctedReferenceHolograms finalReconstructions]))
                        hologramAmplitudeName = strcat(path, "\", hologramName, amplitudeNameString, format);
                        hologramReconstructionDarkFieldName = strcat(path, "\", hologramName, darkFieldNameString, format);
                        hologramPhaseName = strcat(path, "\", hologramName, phaseNameString, format);
                        
                        exportMode = app.ExportModeDropDown.Value;
                        
                        if(exportMode == "Amplitude") 
                            hologramAmplitude = uint8(abs(hologram));
                            hologramAmplitude = imadjust(hologramAmplitude, [amplitudeStartValue/255, amplitudeEndValue/255]);

                            figure('Visible','off');
                            imshow(hologramAmplitude);
                            set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                            savefig(hologramAmplitudeName);
                            close
                        elseif(exportMode == "Phase")
                            if(contains(hologramName,"Reconstruction"))
                                hologramPhase = angle(hologram)*str2double(app.ReconstructionPhaseMultiplierEditField.Value);
                            else
                                hologramPhase = angle(hologram);
                            end

                            hologramPhaseNormalized = (hologramPhase - phaseStartValue) / (phaseEndValue - phaseStartValue);
                            % Clip values to stay within [0,1]
                            hologramPhaseNormalized(hologramPhaseNormalized < 0) = 0;
                            hologramPhaseNormalized(hologramPhaseNormalized > 1) = 1;

                            hologramPhaseNormalized = imadjust(hologramPhaseNormalized, [0 1], [0 1]);
                            figure('Visible','off');
                            imshow(hologramPhaseNormalized);
                            set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                            savefig(hologramPhaseName);
                            close
                        elseif(exportMode == "Both")
                            hologramAmplitude = uint8(abs(hologram));

                            if(contains(hologramName,"Reconstruction"))
                                hologramPhase = angle(hologram)*str2double(app.ReconstructionPhaseMultiplierEditField.Value);
                            else
                                hologramPhase = angle(hologram);
                            end
                            
                            hologramPhaseNormalized = (hologramPhase - phaseStartValue) / (phaseEndValue - phaseStartValue);
                            % Clip values to stay within [0,1]
                            hologramPhaseNormalized(hologramPhaseNormalized < 0) = 0;
                            hologramPhaseNormalized(hologramPhaseNormalized > 1) = 1;

                            hologramPhaseNormalized = imadjust(hologramPhaseNormalized, [0 1], [0 1]);

                            hologramAmplitude = imadjust(hologramAmplitude, [amplitudeStartValue/255, amplitudeEndValue/255]);
                            figure('Visible','off');
                            imshow(hologramAmplitude);
                            set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                            savefig(hologramAmplitudeName);
                            close

                            figure('Visible','off');
                            imshow(hologramPhaseNormalized);
                            set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                            savefig(hologramPhaseName);
                            close
                        end

                        if(ismember(hologramType, finalReconstructions) && app.ExportDarkFieldReconstructionCheckBox.Value == 1)
                            realHologramPart = real(hologram);
                            imaginaryHologramPart = imag(hologram);

                            realImagePartGaussian = imgaussfilt(realHologramPart,30);
                            imaginaryImagePartGaussian = imgaussfilt(imaginaryHologramPart,30);

                            gaussianHologram = realImagePartGaussian + 1i.*imaginaryImagePartGaussian;

                            darkFieldHologram = abs(hologram-gaussianHologram);

                            darkFieldHologramNormalized = (darkFieldHologram - amplitudeStartValue) / (amplitudeEndValue - amplitudeStartValue);
                            % Clip values to stay within [0,1]
                            darkFieldHologramNormalized(darkFieldHologramNormalized < 0) = 0;
                            darkFieldHologramNormalized(darkFieldHologramNormalized > 1) = 1;

                            darkFieldHologramNormalized = imadjust(darkFieldHologramNormalized, [0 1], [0 1]);

                            figure('Visible','off');
                            imshow(darkFieldHologramNormalized);
                            set(gcf,'Visible','off','CreateFcn','set(gcf,''Visible'',''on'')');
                            savefig(hologramReconstructionDarkFieldName);
                            close
                        end     
                    end
                end

                if(contains(formatString,".mat"))
                    format = ".mat";
                    hologramName = strcat(path, "\", hologramName, format);
                    save(hologramName, "hologram");
                end
            end
        end

        function ExportReconstructionData(app, path)
            data = struct();
            props = properties(app);
            appObjectReference = ?HoloApp;
            publicPropertyNumber = 5;
            strangePropertiesCount = 3; % strange properties are the properites that matlab creates at the end of property list
            allProperties = appObjectReference.PropertyList;

            allPrivatePropertiesIndexes = (length(props) + 1 -publicPropertyNumber):length(allProperties) - strangePropertiesCount;
            wantedPrivatePropertiesIndexes = allPrivatePropertiesIndexes([13:16 19:20]);

            for pointIndex = wantedPrivatePropertiesIndexes
                name = allProperties(pointIndex).Name;
                componentClass = app.(name);
                data.(name) = componentClass;
            end

            all = 0;
            data.Wavelengths = app.QCIModel.getWavelengths(all);
            data.CameraPixelSize = app.QCIModel.getCameraPixelSize();

            resultsDataSavePath = strcat(path, "\", "ReconstructionData", ".mat");

            save(resultsDataSavePath,"data");
        end

        function newPropagationDistances = UpdateModelsPropagationDistances(app, newHeight, isPassedForward, startIndex)
            all = 0;
            app.DeterminedPropagationDistances = app.QCIModel.getPropagationDistances(all);

            if(isPassedForward)
                app.DeterminedPropagationDistances(startIndex:end) = newHeight;
            else
                app.DeterminedPropagationDistances(startIndex) = newHeight;
            end

            newPropagationDistances = app.DeterminedPropagationDistances;
            
            app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances);
        end

        function FocusROIUpdateRectangleListener(app, src, event)
            eventName = event.EventName;
            
            switch(eventName)
                case{'ROIMoved'}
                    src.Position = round(event.CurrentPosition);
                    app.FocusROIRectanglePosition = round(event.CurrentPosition);
            end
        end

        function CheckROIVisibility(app, ~, ~)
            % Safety: If ROI doesn't exist, stop
            if isempty(app.FocusROIRectangleObject) || ~isvalid(app.FocusROIRectangleObject)
                return;
            end
        
            % 1. Get Limits & ROI
            xLim = app.FocusUIAxes.XLim;
            yLim = app.FocusUIAxes.YLim;
            
            rPos = app.FocusROIRectangleObject.Position;
            rLeft = rPos(1); rRight = rPos(1) + rPos(3);
            rBottom = rPos(2); rTop = rPos(2) + rPos(4);
            
            % 2. Check Visibility
            isInsideX = (rLeft >= xLim(1)) && (rRight <= xLim(2));
            isInsideY = (rBottom >= yLim(1)) && (rTop <= yLim(2));
            
            if ~isInsideX || ~isInsideY
                % --- ROI IS INVALID: RESET EVERYTHING ---
                
                % A. Uncheck the box
                app.InspectROICheckBox.Value = false;
                
                % B. Delete the visual rectangle
                delete(app.FocusROIRectangleObject);
                
                % C. CRITICAL: Reset position to 0 
                % This ensures the next time they check the box, they MUST draw a new one.
                app.FocusROIRectanglePosition = 0;
                
                % D. Kill the Zoom Listener (Stop monitoring)
                delete(app.AxisZoomListener);
                app.AxisZoomListener = [];
                
                % E. Update Status
                app.ApplicationStateLabel.Text = "ROI Reset (Zoomed too close). Check box to redraw.";
            end
        end
        
        function UpdatePointListener(app, src, event)
            eventName = event.EventName;
            
            pointsStruct = app.ManualCorrectionXY{app.CurrentHologramIndex};

            for pointIndex = 1:3
                if(pointsStruct{pointIndex} == event.PreviousPosition)
                    pointsStruct{pointIndex} = round(event.CurrentPosition);
                    src.Position = round(event.CurrentPosition);
                    break;
                end
            end
            
            switch(eventName)
                case{'ROIMoved'}
                    app.ManualCorrectionXY{app.CurrentHologramIndex} = pointsStruct;
            end

            app.LoadXYTableData();
        end

        function addReconstruction(app, reconstruction, sigma, iterationNumber)
            % addReconstruction Adds a reconstruction to the app model
            arguments
                app
                reconstruction (:,:) {mustBeNumeric}
                sigma (1,1) {mustBeNumeric}
                iterationNumber (1,1) {mustBeNumeric}
            end

            app.ReconstructionSigmas(end + 1) = sigma;
            app.ReconstructionIterationNumber(end + 1) = iterationNumber;

            if(size(app.FinalReconstructions, 1) == 0)
                app.FinalReconstructions(:,:, end) = reconstruction;
                app.FinalReconstructionsNames{1} = convertStringsToChars("ReconstructionS" + num2str(sigma) + ...
                                                     "I" + num2str(iterationNumber));
            else
                app.FinalReconstructions(:,:, end+1) = reconstruction;
                app.FinalReconstructionsNames{end+1} = convertStringsToChars("ReconstructionS" + num2str(sigma) + ...
                                                       "I" + num2str(iterationNumber));
            end
        end

        function insertReconstruction(app, reconstruction, sigma, iterationNumber, name)
            arguments
                app
                reconstruction (:,:) {mustBeNumeric}
                sigma (1,1) {mustBeNumeric}
                iterationNumber (1,1) {mustBeNumeric}
                name
            end
            app.ReconstructionSigmas = [app.ReconstructionSigmas(1:app.CurrentHologramIndex) ...
                                        sigma ...
                                        app.ReconstructionSigmas(app.CurrentHologramIndex+1:end)];
            
            app.ReconstructionIterationNumber = [app.ReconstructionIterationNumber(1:app.CurrentHologramIndex) ...
                                                 iterationNumber ...
                                                 app.ReconstructionIterationNumber(app.CurrentHologramIndex+1:end)];
            if size(app.FinalReconstructions, 3) == 1
                app.FinalReconstructions = cat(3, app.FinalReconstructions(:, :, 1:app.CurrentHologramIndex), ...
                                                  reconstruction);
                app.FinalReconstructionsNames = [app.FinalReconstructionsNames(1:app.CurrentHologramIndex), ...
                                                 {name}];
            else
                app.FinalReconstructions = cat(3, app.FinalReconstructions(:, :, 1:app.CurrentHologramIndex), ...
                                                  reconstruction, ...
                                                  app.FinalReconstructions(:, :, app.CurrentHologramIndex+1:end));
                app.FinalReconstructionsNames = [app.FinalReconstructionsNames(1:app.CurrentHologramIndex), ...
                                                 {name}, ...
                                                 app.FinalReconstructionsNames(app.CurrentHologramIndex+1:end)];
            end
        end

        function outputImage = cropAndPadImages(app, inputImage, cropPadVector)
            % cropAndPadImages Crops and pads an image asymmetrically
            % cropPadVector = [top, bottom, left, right]
        
            arguments
                app
                inputImage (:,:,:) {mustBeNumeric}
                cropPadVector (1,4) {mustBeInteger, mustBeNonnegative}
            end
        
            if all(cropPadVector == 0)
                outputImage = inputImage;
                return
            end
        
            [topCrop, bottomCrop, leftCrop, rightCrop] = deal(cropPadVector(1), cropPadVector(2), cropPadVector(3), cropPadVector(4));
        
            % Crop the image according to cropPadVector
            croppedImage = inputImage(1+topCrop:end-bottomCrop, 1+leftCrop:end-rightCrop, :);
        
            % Pad vertically (top and bottom)
            paddedVertically = padarray(croppedImage, [topCrop, 0], 'replicate', 'pre');
            paddedVertically = padarray(paddedVertically, [bottomCrop, 0], 'replicate', 'post');
        
            % Pad horizontally (left and right)
            paddedHorizontally = padarray(paddedVertically, [0, leftCrop], 'replicate', 'pre');
            outputImage = padarray(paddedHorizontally, [0, rightCrop], 'replicate', 'post');
        end

        function CenterUIAxes(app, ax, xCenter, yCenter)
            % Get current axis limits
            xLimits = ax.XLim;
            yLimits = ax.YLim;
            
            % Check if the center point is already within the current limits
            if xCenter >= xLimits(1) && xCenter <= xLimits(2) && ...
               yCenter >= yLimits(1) && yCenter <= yLimits(2)
                return; % Do nothing if already in view
            end
            
            % Calculate current window width and height
            xWidth = xLimits(2) - xLimits(1);
            yHeight = yLimits(2) - yLimits(1);
            
            % Compute new axis limits centered at (xCenter, yCenter)
            newXLimits = [xCenter - xWidth / 2, xCenter + xWidth / 2];
            newYLimits = [yCenter - yHeight / 2, yCenter + yHeight / 2];
            
            % Apply new limits
            ax.XLim = newXLimits;
            ax.YLim = newYLimits;
        end

        function DisableInterface(app)
            % Disable Menu items and general controls
            app.DisableMenu();
            app.SaveSessionButton.Enable = "off";
            app.GPUProcessingCheckBox.Enable = "off";
            
            % --- Load Data Panel ---
            app.LoadDataFilesListBox.Enable = "off";
            app.LoadHologramsButton.Enable = "off";
            app.PixelSizeEditField.Enable = "off";
            app.WavelengthEditField.Enable = "off";
            app.HeightEditField.Enable = "off";
            app.ModeDropDown.Enable = "off";
            app.PhotoWavelengthTable.Enable = "off";
            app.PhotoHeightTable.Enable = "off";
            app.LoadDataRangeStartEditField.Enable = "off";
            app.LoadDataRangeEndEditField.Enable = "off";
            app.LoadDataNextButton.Enable = "off";
            app.LoadDataPreviousButton.Enable = "off";
            
            % --- Focus Panel ---
            app.FocusFilesListBox.Enable = "off";
            app.StepEditField.Enable = "off";
            app.CurrentHeightSubtractButton.Enable = "off";
            app.CurrentHeightEditField.Enable = "off";
            app.CurrentHeightAddButton.Enable = "off";
            app.FocusAmplitudeButton.Enable = "off";
            app.FocusPhaseButton.Enable = "off";
            app.FocusNextButton.Enable = "off";
            app.FocusPreviousButton.Enable = "off";
            
            % --- Shift Panel ---
            app.ShiftFilesListBox.Enable = "off";
            app.ShiftAmplitudeButton.Enable = "off";
            app.ShiftPhaseButton.Enable = "off";
            app.ShiftNextButton.Enable = "off";
            app.ShiftPreviousButton.Enable = "off";
            app.ManualcorrectButton.Enable = "off";
            app.AutocorrectButton.Enable = "off";
            app.ShiftHologramTypeDropDown.Enable = "off";
            app.ShiftRangeStartEditField.Enable = "off";
            app.ShiftRangeEndEditField.Enable = "off";
            app.GoToRed.Enable = "off";
            app.GoToGreen.Enable = "off";
            app.GoToBlue.Enable = "off";
            app.CropAndPadLeftButton.Enable = "off";
            app.CropAndPadBottomButton.Enable = "off";
            app.CropAndPadRightButton.Enable = "off";
            app.LayerNumberEditField.Enable = "off";
            
            % --- Reconstruction Panel ---
            app.ReconstructionHologramsListBox.Enable = "off";
            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionPhaseButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "off";
            app.ReconstructionNextButton.Enable = "off";
            app.ReconstructionPreviousButton.Enable = "off";
            app.ReconstructionRangeStartEditField.Enable = "off";
            app.ReconstructionRangeEndEditField.Enable = "off";
            app.ReconstructionPhaseMultiplierEditField.Enable = "off";
            
            % --- Results Panel ---
            app.AvailableHologramsListBox.Enable = "off";
            app.ExportedHologramsListBox.Enable = "off";
            app.ExportdataButton.Enable = "off";
            app.ExportModeDropDown.Enable = "off";
            app.FormatDropDown.Enable = "off";
            app.AmplitudeStartValue.Enable = "off";
            app.AmplitudeEndValue.Enable = "off";
            app.PhaseStartValue.Enable = "off";
            app.PhaseEndValue.Enable = "off";
            app.ExportDarkFieldReconstructionCheckBox.Enable = "off";
            app.MoveAllLeft.Enable = "off";
            app.MoveSingleLeft.Enable = "off";
            app.MoveSingleRight.Enable = "off";
            app.MoveAllRight.Enable = "off";

            pause(.1);
        end

        function DisableMenu(app)
            app.LoadDataButton.Enable = "off";
            app.FocusButton.Enable = "off";
            app.ShiftButton.Enable = "off";
            app.ReconstructionButton.Enable = "off";
            app.ExportResultsButton.Enable = "off";
        end
        
        function EnableInterface(app)
            loadedHologramCount = size(app.HologramsRaw,3);

            % Enable Menu items and general controls
            app.EnableMenu();
            app.SaveSessionButton.Enable = "on";
            app.GPUProcessingCheckBox.Enable = "on";
            
            % --- Load Data Panel ---
            app.LoadDataFilesListBox.Enable = "on";
            app.LoadHologramsButton.Enable = "on";
            app.PixelSizeEditField.Enable = "on";
            app.WavelengthEditField.Enable = "on";
            app.HeightEditField.Enable = "on";
            app.ModeDropDown.Enable = "on";
            app.PhotoWavelengthTable.Enable = "on";
            app.PhotoHeightTable.Enable = "on";
            app.LoadDataRangeStartEditField.Enable = "on";
            app.LoadDataRangeEndEditField.Enable = "on";
            app.LoadDataNextButton.Enable = "on";
            app.LoadDataPreviousButton.Enable = "on";
            
            % --- Focus Panel ---
            app.FocusFilesListBox.Enable = "on";
            app.StepEditField.Enable = "on";
            app.CurrentHeightSubtractButton.Enable = "on";
            app.CurrentHeightEditField.Enable = "on";
            app.CurrentHeightAddButton.Enable = "on";
            app.FocusAmplitudeButton.Enable = "on";
            app.FocusPhaseButton.Enable = "on";
            app.FocusNextButton.Enable = "on";
            app.FocusPreviousButton.Enable = "on";
            
            if(loadedHologramCount > 1)
                % --- Shift Panel ---
                app.ShiftFilesListBox.Enable = "on";
                app.ShiftAmplitudeButton.Enable = "on";
                app.ShiftPhaseButton.Enable = "on";
                app.ShiftNextButton.Enable = "on";
                app.ShiftPreviousButton.Enable = "on";
                app.ManualcorrectButton.Enable = "on";
                app.AutocorrectButton.Enable = "on";
                app.ShiftHologramTypeDropDown.Enable = "on";
                app.ShiftRangeStartEditField.Enable = "on";
                app.ShiftRangeEndEditField.Enable = "on";
                app.GoToRed.Enable = "on";
                app.GoToGreen.Enable = "on";
                app.GoToBlue.Enable = "on";
                app.CropAndPadLeftButton.Enable = "on";
                app.CropAndPadBottomButton.Enable = "on";
                app.CropAndPadRightButton.Enable = "on";
                app.LayerNumberEditField.Enable = "on";
                
                % --- Reconstruction Panel ---
                app.ReconstructionHologramsListBox.Enable = "on";
                app.ReconstructionAmplitudeButton.Enable = "on";
                app.ReconstructionPhaseButton.Enable = "on";
                app.ReconstructionDarkFieldButton.Enable = "on";
                app.ReconstructionNextButton.Enable = "on";
                app.ReconstructionPreviousButton.Enable = "on";
                app.ReconstructionRangeStartEditField.Enable = "on";
                app.ReconstructionRangeEndEditField.Enable = "on";
                app.ReconstructionPhaseMultiplierEditField.Enable = "off";
            end
            % --- Results Panel ---
            app.AvailableHologramsListBox.Enable = "on";
            app.ExportedHologramsListBox.Enable = "on";
            app.ExportdataButton.Enable = "on";
            app.ExportModeDropDown.Enable = "on";
            app.FormatDropDown.Enable = "on";
            app.AmplitudeStartValue.Enable = "on";
            app.AmplitudeEndValue.Enable = "on";
            app.PhaseStartValue.Enable = "on";
            app.PhaseEndValue.Enable = "on";
            app.ExportDarkFieldReconstructionCheckBox.Enable = "on";
            app.MoveAllLeft.Enable = "on";
            app.MoveSingleLeft.Enable = "on";
            app.MoveSingleRight.Enable = "on";
            app.MoveAllRight.Enable = "on";

            pause(.1);
        end

        function EnableMenu(app)
            loadedHologramCount = size(app.HologramsRaw,3);

            app.LoadDataButton.Enable = "on";
            app.FocusButton.Enable = "on";

            if(loadedHologramCount > 1)
                app.ShiftButton.Enable = "on";
                app.ReconstructionButton.Enable = "on";
            end
            
            app.ExportResultsButton.Enable = "on";
        end

        function SaveAppState(app)
            state = struct();
            props = properties(app);

            for pointIndex = 1:length(props)
                name = props{pointIndex};
                component = app.(name);  % May be a component or a property, so far...
                componentClass = string(class(component));
                if componentClass == "matlab.ui.control.NumericEditField" ...
                        || componentClass == "matlab.ui.control.EditField" ...
                        || componentClass == "matlab.ui.control.CheckBox"
                    state.(name)= component.Value;
                elseif componentClass == "matlab.ui.control.DropDown"
                    % These are weird, had to be specially handled to save a string
                    % instead of an index into the list:
                    v = component.Value;
                    id = find(strcmp(component.Items,v));
                    
                    if  ~isempty(v) && ~isempty(id) 
                        state.(name)= component.Items{id};
                    end

                elseif componentClass == "matlab.ui.container.CheckBoxTree"
                    for ii = 1:size(component.CheckedNodes,1)
                        tag(ii) = string(app.(name).CheckedNodes(ii).Tag);
                    end

                    if exist('tag','var')
                        state.(name) = tag;
                    end

                elseif componentClass == "matlab.ui.control.ListBox" 
                    state.(name) = component.Items;
                elseif componentClass == "matlab.ui.control.Table"
                    state.(name) = component.Data;
                end 
            end

            appObjectReference = ?HoloApp;
            publicPropertyNumber = 5;
            strangePropertiesCount = 3; % strange properties are the properites that matlab creates at the end of property list
            allProperties = appObjectReference.PropertyList;

            allPrivatePropertiesIndexes = (length(props) + 1 -publicPropertyNumber):length(allProperties) - strangePropertiesCount;
            wantedPrivatePropertiesIndexes = allPrivatePropertiesIndexes([1:4 9:22 28 29]);

            for pointIndex = wantedPrivatePropertiesIndexes
                name = allProperties(pointIndex).Name;
                componentClass = app.(name);
                state.(name) = componentClass;
            end
            
            all = 0;

            savePathMat = app.Files(1).folder + "\SavedSession.mat";
            state.QCIWavelengths = app.QCIModel.getWavelengths(all);
            state.QCIPropagationDistances = app.DeterminedPropagationDistances;

            save(savePathMat, 'state', '-v7.3');

            savePathQCIHologramFile = app.Files(1).folder + "\SavedSession.qcihf";
            app.SaveHologramsToQCIBinary(savePathQCIHologramFile);    
        end

        function SaveHologramsToQCIBinary(app, filePath)
            fileID = fopen(filePath, 'w');
            if fileID == -1
                error('Cannot open file for writing: %s', filePath);
            end
        
            try
                % Write real HologramsRaw
                dimensions = size(app.HologramsRaw);
                if length(dimensions) == 2
                    dimensions(3) = 1;
                end

                fwrite(fileID, dimensions, 'uint32');
                fwrite(fileID, app.HologramsRaw(:), 'double');
        
                % Write HologramsRawNames
                fwrite(fileID, numel(app.HologramsRawNames), 'uint32');
                for i = 1:numel(app.HologramsRawNames)
                    name = app.HologramsRawNames{i};
                    fwrite(fileID, length(name), 'uint32');
                    fwrite(fileID, name, 'char');
                end
        
                % Write complex FinalReconstructions
                dimensions = size(app.FinalReconstructions);
                if length(dimensions) == 2
                    dimensions(3) = 1;
                end
                
                fwrite(fileID, dimensions, 'uint32');
                fwrite(fileID, real(app.FinalReconstructions(:)), 'double');
                fwrite(fileID, imag(app.FinalReconstructions(:)), 'double');
        
                % Write FinalReconstructionsNames
                fwrite(fileID, numel(app.FinalReconstructionsNames), 'uint32');
                for i = 1:numel(app.FinalReconstructionsNames)
                    name = app.FinalReconstructionsNames{i};
                    fwrite(fileID, length(name), 'uint32');
                    fwrite(fileID, name, 'char');
                end
            catch error
                fclose(fileID);
                rethrow(error);
            end
        
            fclose(fileID);
        end

        function LoadAppState(app, loadPath)
            matLoalPath = loadPath + "\SavedSession.mat";
            load(matLoalPath, 'state');
            fields = fieldnames(state);
            isFirstPrivateVariable = cellfun(@(x)isequal(x,'CurrentHologramIndex'),fields);
            [firstPrivateVariableIndex, ~] = find(isFirstPrivateVariable);
            
            privatePropertyCount = 20;

            for pointIndex = 1:length(fields)-privatePropertyCount
                name = fields{pointIndex};
                try   % or could use isprop(app, name) to test presence
                        comp = app.(name);
                    if class(comp) == "matlab.ui.container.CheckBoxTree"
                        tags = state.(name);
                        for ii = 1:size(tags,2)
                            checkedNodes(ii) = findall(0,'tag',tags(ii));
                        end
                        app.(name).CheckedNodes = checkedNodes;
                    elseif class(comp) == "matlab.ui.control.ListBox"
                        comp.Items = state.(name);
                        comp.ItemsData = 1:length(comp.Items);
                    elseif class(comp) == "matlab.ui.control.Table"
                        comp.Data = state.(name);
                    else
                        comp.Value = state.(name);
                    end
                catch err
                    disp(['Did not use saved field', name, ': ', err.identifier])
                end
            end
            
            qcihfLoadPath = loadPath + "\SavedSession.qcihf";
            app.LoadHologramsFromQCIBinary(qcihfLoadPath);
            
            QCIModelDataCount = 3;

            for pointIndex = firstPrivateVariableIndex:(length(fields) - QCIModelDataCount)
                name = fields{pointIndex};
                app.(name) = state.(name);
            end
            
            if(~isempty(app.HologramsRaw) && ~isempty(state.QCIWavelengths) && ~isempty(state.QCIPropagationDistances))
                names = app.HologramsRawNames;
                holograms = app.HologramsRaw;
                wavelengths = state.QCIWavelengths;
                propagationDistances = state.QCIPropagationDistances;
                pixelSize = app.PixelSizeEditField.Value;

                if(app.GPUProcessingStatus)
                    app.QCIModel = QCI_Model_GPU(names, holograms, wavelengths, propagationDistances, pixelSize);
                else
                    app.QCIModel = QCI_Model(names, holograms, wavelengths, propagationDistances, pixelSize);
                end

                if(~isempty(app.DeterminedPropagationDistances))
                    for i = 1: size(app.HologramsRaw,3)
                        app.HologramsPostPropagation(:, :, i) = app.QCIModel.propagate(i);
                    end
                end

                if(~isempty(app.CorrectedPropagationDistances) && isempty(app.ManualCorrectionXY))
                    all = 0;
                    try
                        [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedPropagationDistances, ~] = app.QCIModel.automaticalShiftAndScalingCorrection(app.HologramsPostPropagation);
                    catch error
                        popupString =  "Could not perform automatical shift and scaling correction because:"+ newline + error.message + newline +"Try the manual one instead." + newline;
                        popupApp = PopupConfirm(app, popupString);
            
                        while isvalid(popupApp); pause(0.1); end
                        
                        app.ApplicationStateLabel.Text = "Idle";
                        return
                    end
                    
                    app.DeterminedPropagationDistances = app.QCIModel.getPropagationDistances(all);  
                                     
                    allIndexes =  1:size(app.CorrectedHolograms,3);
                    app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                    app.QCIModel.setPropagationDistances(app.CorrectedPropagationDistances, allIndexes);
        
                    for index = allIndexes
                        app.CorrectedReference(:,:,index) = app.QCIModel.propagate(index);
                    end

                    app.QCIModel.setHolograms(app.HologramsRaw, allIndexes);
                    app.QCIModel.setPropagationDistances(state.QCIPropagationDistances, allIndexes);
                elseif(app.CorrectionModeDropDown.Value == "OnlyShift")
                    app.CorrectedReference = [];
                    app.CorrectedHolograms = [];
    
                    [app.CorrectedReference, rowShifts, columnShifts] = app.QCIModel.automaticalShiftCorrection(app.HologramsPostPropagation); 
    
                    for index = 1:length(rowShifts)-1
                        app.CorrectedHolograms(:,:,index) = app.QCIModel.shiftHologram(app.HologramsRaw(:,:,index), columnShifts(index), rowShifts(index));
                    end
    
                    app.CorrectedHolograms(:,:,end+1) = app.HologramsRaw(:,:,end);
    
                    allIndexes =  1:size(app.CorrectedHolograms,3);
                    app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                end

                if ~isempty(app.ManualCorrectionXY)
                    if strcmp(app.CorrectionModeDropDown.Value, "ShiftAndScaling")
                        % Recreate manual shift-and-scaling correction based on saved manual points.
                        [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedPropagationDistances] = ...
                            app.QCIModel.threePointShiftAndScalingCorrection(app.HologramsPostPropagation, app.ManualCorrectionXY);
                        all = 0;
                        app.DeterminedPropagationDistances = app.QCIModel.getPropagationDistances(all);
                        allIndexes = 1:size(app.CorrectedHolograms, 3);
                        app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                        for index = allIndexes
                            app.CorrectedReference(:, :, index) = app.QCIModel.propagate(index);
                        end
                    elseif strcmp(app.CorrectionModeDropDown.Value, "OnlyShift")
                        % For OnlyShift, use the first manual point from the last image as the reference.
                        referencePoint = app.ManualCorrectionXY{end}{1};
                        for index = 1:size(app.HologramsPostPropagation, 3)-1
                            shiftedPoint = app.ManualCorrectionXY{index}{1};
                            differenceBetweenPoints = referencePoint - shiftedPoint;
                            app.CorrectedHolograms(:, :, index) = app.QCIModel.shiftHologram(...
                                app.HologramsRaw(:, :, index), differenceBetweenPoints(1), differenceBetweenPoints(2));
                            app.CorrectedReference(:, :, index) = app.QCIModel.shiftHologram(...
                                app.HologramsPostPropagation(:, :, index), differenceBetweenPoints(1), differenceBetweenPoints(2));
                        end
                        % The last hologram is left uncorrected.
                        app.CorrectedHolograms(:, :, end+1) = app.HologramsRaw(:, :, end);
                        app.CorrectedReference(:, :, end+1) = app.HologramsPostPropagation(:, :, end);
                    end
                end
            end
        end

        function LoadHologramsFromQCIBinary(app, filePath)
            fileID = fopen(filePath, 'r');
            if fileID == -1
                error('Cannot open file for reading: %s', filePath);
            end
        
            try
                % Read HologramsRaw
                dimensions = fread(fileID, 3, 'uint32')';
                numElements = prod(dimensions);
                app.HologramsRaw = reshape(fread(fileID, numElements, 'double'), dimensions);
        
                % Read HologramsRawNames
                numNames = fread(fileID, 1, 'uint32');
                app.HologramsRawNames = cell(1, numNames);
                for i = 1:numNames
                    len = fread(fileID, 1, 'uint32');
                    app.HologramsRawNames{i} = char(fread(fileID, len, 'char')');
                end
        
                % Read FinalReconstructions
                dimensions = fread(fileID, 3, 'uint32')';
                
                numElements = prod(dimensions);
                realPart = fread(fileID, numElements, 'double');
                imagPart = fread(fileID, numElements, 'double');
                app.FinalReconstructions = reshape(complex(realPart, imagPart), dimensions);
        
                % Read FinalReconstructionsNames
                numNames = fread(fileID, 1, 'uint32');
                app.FinalReconstructionsNames = cell(1, numNames);
                for i = 1:numNames
                    len = fread(fileID, 1, 'uint32');
                    app.FinalReconstructionsNames{i} = char(fread(fileID, len, 'char')');
                end
            catch error
                fclose(fileID);
                rethrow(error);
            end
        
            fclose(fileID);
        end

    end
    %Controller

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axList = [app.LoadDataUIAxes, app.FocusUIAxes, app.ShiftUIAxes, app.ReconstructionUIAxes];

            for ax = axList
                if isempty(ax) || ~isvalid(ax)
                    continue
                end
        
                currentInteractions = ax.Interactions;
                if isempty(currentInteractions)
                    continue
                end
        
                keepMask = true(size(currentInteractions));
                for k = 1:numel(currentInteractions)
                    interactionClass = class(currentInteractions(k));
        
                    % Remove only DataTip-related interactions (these typically draw the hover marker)
                    if contains(interactionClass, "DataTip", "IgnoreCase", true) || ...
                       contains(interactionClass, "Datatip", "IgnoreCase", true)
                        keepMask(k) = false;
                    end
                end
        
                ax.Interactions = currentInteractions(keepMask);
            end
        end

        % Selection change function: NavigationTab
        function NavigationTabSelectionChanged(app, event)
            if(app.LoadDataButton.Value == 1)
                app.NavigationTab.SelectedTab = app.LoadDataTab;
            elseif(app.FocusButton.Value == 1)
                app.NavigationTab.SelectedTab = app.FocusTab;
            elseif(app.ShiftButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ShiftTab;
            elseif(app.ReconstructionButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ReconstructionTab;
            elseif(app.ExportResultsButton.Value == 1)
                app.NavigationTab.SelectedTab = app.ResultsTab;
            end
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
           app.NavigationTab.SelectedTab = app.LoadDataTab;
           app.FocusButton.Value = 0;
           app.ShiftButton.Value = 0;
           app.ExportResultsButton.Value = 0;
           app.ReconstructionButton.Value = 0;
           pause(.1)

           %Load the current propagation image;
           app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: FocusButton
        function FocusButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.FocusTab;
            app.LoadDataButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            app.ReconstructionButton.Value = 0;
            pause(.1)

            %Check current model hologram
            firstPropagationDistance = app.QCIModel.getPropagationDistances(1);
            if(~isempty(app.CorrectedHolograms(:,:,1)) && firstPropagationDistance == app.CorrectedPropagationDistances(1))
                app.QCIModel.setNames(app.HologramsRawNames, 0);
                app.QCIModel.setHolograms(app.HologramsRaw, []);
                app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances, []);
            end

            %Load the current propagation image;
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ShiftButton
        function ShiftButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ShiftTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ReconstructionButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            pause(.1)

            %Check current model hologram
            firstPropagationDistance = app.QCIModel.getPropagationDistances(1);
            if(~isempty(app.CorrectedHolograms(:,:,1)) && firstPropagationDistance == app.CorrectedPropagationDistances(1))
                app.QCIModel.setNames(app.HologramsRawNames, 0);
                app.QCIModel.setHolograms(app.HologramsRaw, []);
                app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances,[]);
            end
            
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ReconstructionButton
        function ReconstructionButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ReconstructionTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ExportResultsButton.Value = 0;
            pause(.1)

            if(~isempty(app.CorrectedReference))
                if(sum(app.CropAndPadVector) ~= 0)
                    holograms = app.cropAndPadImages(app.CorrectedHolograms, app.CropAndPadVector);
                else
                    holograms = app.CorrectedHolograms;
                end

                app.QCIModel.setHolograms(holograms, []);
                app.QCIModel.setPropagationDistances(app.CorrectedPropagationDistances,[]);
            else
                app.QCIModel.setHolograms(app.HologramsRaw, []);
                app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances,[]);
            end

            firstHologramName = app.QCIModel.getNames(1);
            if(~isempty(app.CorrectedHolograms(:,:,1)) && firstHologramName == app.HologramsRawNames{1})
                app.QCIModel.setNames(app.CorrectedHologramsNames, 0);

                %app.CorrectedHolograms = app.HologramsRaw;
                app.QCIModel.setHolograms(app.CorrectedHolograms, 0);
                app.QCIModel.setPropagationDistances(app.CorrectedPropagationDistances, []);
            end
            
            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: ExportResultsButton
        function ExportResultsButtonValueChanged(app, event)
            % Selected button change
            app.NavigationTab.SelectedTab = app.ResultsTab;
            app.LoadDataButton.Value = 0;
            app.FocusButton.Value = 0;
            app.ShiftButton.Value = 0;
            app.ReconstructionButton.Value = 0;

            % Saved amplitude and phase ranges
            app.PhaseStartValue.Value  = num2str(app.PhaseDisplayRange(1));
            app.PhaseEndValue.Value = num2str(app.PhaseDisplayRange(2));
            app.AmplitudeStartValue.Value  = num2str(app.AmplitudeDisplayRange(1));
            app.AmplitudeEndValue.Value  = num2str(app.AmplitudeDisplayRange(2));

            if(isempty(app.ExportedHologramsListBox.Items) && isempty(app.AvailableHologramsListBox.Items))
                app.LoadExportResultsAvailableHologramsListBox();
            end
        end

        % Button pushed function: LoadHologramsButton
        function LoadHologramsButtonPushed(app, event)
             app.DisableInterface();

             % display app state
             app.ApplicationStateLabel.Text = "Selecting directory...";

             selectedPath = uigetdir();
             
             % Load state from SavedSession.mat
             try
                fileList = dir(fullfile(selectedPath, 'SavedSession.mat'));
             catch err
                 app.EnableInterface();
        
                 % display app state
                 app.ApplicationStateLabel.Text = "Idle";

                 app.DisableMenu();

                 return
             end
                 
             if(~isempty(fileList))
                popupApp = PopupYesNo(app, "Found the settings from previous session. Do you wish to load them?");

                while isvalid(popupApp); pause(0.1); end
                    
                if(app.PopupStatus)
                    %Display app state
                    app.ApplicationStateLabel.Text = "Loading previous session";
    
                    path = selectedPath;
                    app.LoadAppState(path);
    
                    image = app.QCIModel.getHolograms(app.CurrentHologramIndex);
                    imshow(uint8(image), "Parent",app.LoadDataUIAxes);
                    
                    app.EnableInterface();
                    app.SessionStartDateTime = replace(erase(string(datetime),":"), " ", "_");
                    %Display app state
                    app.ApplicationStateLabel.Text = "Idle";
                    return
                end
             end

             %Load photos to the app.
             if(selectedPath ~= 0)
                 app.Files = [];
                 files = dir(selectedPath);
                 files = files(~ismember({files.name}, {'.', '..'}));

                 filtred = zeros(1,length(files));

                 for i = 1:length(files)
                     if (contains(files(i).name,".mat"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,".png"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,".tif"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,".bmp"))
                         filtred(i) = 1;
                     end

                     if (contains(files(i).name,"SavedSession.mat"))
                         filtred(i) = 0;
                     end
                 end

                 files = files(filtred == 1);

                 %Display app state
                 app.ApplicationStateLabel.Text = "Selecting data...";
                 pause(.1)

                 %Model
                 app.Files = files;

                 if ~isempty(app.HoloPrepHandle) && isvalid(app.HoloPrepHandle)
                    return; % Exit this callback immediately so we don't spawn another one
                 end
     
                 app.HoloPrepHandle = HoloPrep(app);

                 while isvalid(app.HoloPrepHandle); pause(0.1); end
                 
                if(app.PopupStatus == -1) %Failed to load the data
                    app. LoadDataButton.Enable = "on";
                    app.GPUProcessingCheckBox.Enable = "on";
            
                    % Load Data
                    app.LoadDataFilesListBox.Enable = "on";
                    app.LoadHologramsButton.Enable = "on";
                    app.PixelSizeEditField.Enable = "on";
                    app.WavelengthEditField.Enable = "on";
                    app.HeightEditField.Enable = "on";
                    app.ModeDropDown.Enable = "on";
                    app.PhotoWavelengthTable.Enable =  "on";
                    app.PhotoHeightTable.Enable = "on";
        
                    app.LoadDataRangeStartEditField.Enable = "on";
                    app.LoadDataRangeEndEditField.Enable = "on";
                    app.LoadDataNextButton.Enable = "on";
                    app.LoadDataPreviousButton.Enable = "on";
                    app.ApplicationStateLabel.Text = "Idle";
                    return 
                end

                 %Display app state
                 app.ApplicationStateLabel.Text = "Loading data...";
                 pause(.1)
                 
                 app.LoadHologramsToQCIModel();
                 app.CurrentHologramIndex = 1;

                 % app state reset
                 app.SessionStartDateTime = replace(erase(string(datetime),":"), " ", "_");
                 app.CorrectedReference = [];
                 app.CorrectedHolograms = [];
                 app.FinalReconstructions = [];
                 app.ReconstructionSigmas = [];
                 app.ReconstructionIterationNumber = [];
                 app.DeterminedPropagationDistances = [];
                
                 % View
                 app.XYTable.Data = [];
                 app.LoadHologramsListBox();
                 app.LoadModeTableData();

                app.HandleHologramChange(app.CurrentHologramIndex);
             end

             app.EnableInterface();

             % display app state
             app.ApplicationStateLabel.Text = "Idle";
        end

        % Value changed function: LoadDataFilesListBox
        function LoadDataFilesListBoxValueChanged(app, event)
            app.HandleHologramChange(app.LoadDataFilesListBox.Value);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            HandleModeChange(app, app.ModeDropDown.Value);
        end

        % Value changed function: FocusFilesListBox
        function FocusFilesListBoxValueChanged(app, event)
            app.HandleHologramChange(app.FocusFilesListBox.Value);
        end

        % Value changed function: ShiftFilesListBox
        function ShiftFilesListBoxValueChanged(app, event)
            app.HandleHologramChange(app.ShiftFilesListBox.Value);
        end

        % Value changed function: ReconstructionHologramsListBox
        function ReconstructionHologramsListBoxValueChanged(app, event)
            app.HandleHologramChange(app.ReconstructionHologramsListBox.Value);
        end

        % Value changed function: FocusRangeStartEditField, 
        % ...and 3 other components
        function RangeStartEditFieldValueChanged(app, event)
            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                app.AmplitudeDisplayRange(1) = app.EvaluateMathExpression(app.LoadDataRangeStartEditField.Value);
                app.LoadDataRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                if(app.FocusAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.EvaluateMathExpression(app.FocusRangeStartEditField.Value);
                    app.FocusRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                else
                    app.PhaseDisplayRange(1) = app.EvaluateMathExpression(app.FocusRangeStartEditField.Value);
                    app.FocusRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                end
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                if(app.ShiftAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.EvaluateMathExpression(app.ShiftRangeStartEditField.Value);
                    app.ShiftRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                else
                    app.PhaseDisplayRange(1) = app.EvaluateMathExpression(app.ShiftRangeStartEditField.Value);
                    app.ShiftRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                end
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                if(app.ReconstructionAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.EvaluateMathExpression(app.ReconstructionRangeStartEditField.Value);
                    app.ReconstructionRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                elseif(app.ReconstructionPhaseButton.Value == 1)
                    app.PhaseDisplayRange(1) = app.EvaluateMathExpression(app.ReconstructionRangeStartEditField.Value);
                    app.ReconstructionRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
                elseif(app.ReconstructionDarkFieldButton.Value == 1)
                    app.AmplitudeDisplayRange(1) = app.EvaluateMathExpression(app.ReconstructionRangeStartEditField.Value);
                    app.ReconstructionRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
                end
            end

            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Value changed function: FocusRangeEndEditField, 
        % ...and 3 other components
        function RangeEndEditFieldValueChanged(app, event)

            if(app.NavigationTab.SelectedTab == app.LoadDataTab)
                app.AmplitudeDisplayRange(2) = app.EvaluateMathExpression(app.LoadDataRangeEndEditField.Value);
                app.LoadDataRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
            elseif(app.NavigationTab.SelectedTab == app.FocusTab)
                if(app.FocusAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.EvaluateMathExpression(app.FocusRangeEndEditField.Value);
                    app.FocusRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
                else
                    app.PhaseDisplayRange(2) = app.EvaluateMathExpression(app.FocusRangeEndEditField.Value);
                    app.FocusRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));
                end
            elseif(app.NavigationTab.SelectedTab == app.ShiftTab)
                if(app.ShiftAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.EvaluateMathExpression(app.ShiftRangeEndEditField.Value);
                    app.ShiftRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
                else
                    app.PhaseDisplayRange(2) = app.EvaluateMathExpression(app.ShiftRangeEndEditField.Value);
                    app.ShiftRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));
                end
            elseif(app.NavigationTab.SelectedTab == app.ReconstructionTab)
                if(app.ReconstructionAmplitudeButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.EvaluateMathExpression(app.ReconstructionRangeEndEditField.Value);
                    app.ReconstructionRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
                elseif(app.ReconstructionPhaseButton.Value == 1)
                    app.PhaseDisplayRange(2) = app.EvaluateMathExpression(app.ReconstructionRangeEndEditField.Value);
                    app.ReconstructionRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));
                elseif(app.ReconstructionDarkFieldButton.Value == 1)
                    app.AmplitudeDisplayRange(2) = app.EvaluateMathExpression(app.ReconstructionRangeEndEditField.Value);
                    app.ReconstructionRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));
                end
            end

            app.HandleHologramChange(app.CurrentHologramIndex);
        end

        % Button pushed function: FocusNextButton, LoadDataNextButton, 
        % ...and 2 other components
        function NextButtonValueChanged(app, event)
             app.HandleHologramChange(app.CurrentHologramIndex + 1);
        end

        % Button pushed function: FocusPreviousButton, 
        % ...and 3 other components
        function PreviousButtonValueChanged(app, event)
            app.HandleHologramChange(app.CurrentHologramIndex - 1);
        end

        % Value changed function: FocusAmplitudeButton
        function FocusAmplitudeButtonValueChanged(app, event)
            app.FocusPhaseButton.Value = ~app.FocusAmplitudeButton.Value;
            app.FocusAmplitudeButton.Enable = "off";
            app.FocusPhaseButton.Enable = "on";

            app.FocusRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
            app.FocusRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

            app.DisplayFocus();
        end

        % Value changed function: FocusPhaseButton
        function FocusPhaseButtonValueChanged(app, event)
            app.FocusAmplitudeButton.Value = ~app.FocusPhaseButton.Value;
            app.FocusPhaseButton.Enable = "off";
            app.FocusAmplitudeButton.Enable = "on";

            app.FocusRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
            app.FocusRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));

            app.DisplayFocus();
        end

        % Value changed function: ShiftAmplitudeButton
        function ShiftAmplitudeButtonValueChanged(app, event)
            app.ShiftPhaseButton.Value = ~app.ShiftAmplitudeButton.Value;
            app.ShiftAmplitudeButton.Enable = "off";
            app.ShiftPhaseButton.Enable = "on";

            app.ShiftRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
            app.ShiftRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: ShiftPhaseButton
        function ShiftPhaseButtonValueChanged(app, event)
            app.ShiftAmplitudeButton.Value = ~app.ShiftPhaseButton.Value;
            app.ShiftAmplitudeButton.Enable = "on";
            app.ShiftPhaseButton.Enable = "off";

            app.ShiftRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
            app.ShiftRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Button pushed function: GoToRed
        function GoToRedButtonPushed(app, event)
            currentRedXY = app.ManualCorrectionXY{app.CurrentHologramIndex}{1}; 
            app.CenterUIAxes(app.ShiftUIAxes, currentRedXY(1), currentRedXY(2));
        end

        % Button pushed function: GoToGreen
        function GoToGreenButtonPushed(app, event)
            currentGreenXY = app.ManualCorrectionXY{app.CurrentHologramIndex}{2}; 
            app.CenterUIAxes(app.ShiftUIAxes, currentGreenXY(1), currentGreenXY(2));
        end

        % Button pushed function: GoToBlue
        function GoToBlueButtonPushed(app, event)
            currentBlueXY = app.ManualCorrectionXY{app.CurrentHologramIndex}{3}; 
            app.CenterUIAxes(app.ShiftUIAxes, currentBlueXY(1), currentBlueXY(2));
        end

        % Value changed function: ReconstructionAmplitudeButton
        function ReconstructionAmplitudeButtonValueChanged(app, event)
            app.ReconstructionPhaseButton.Value = ~app.ReconstructionAmplitudeButton.Value;
            app.ReconstructionDarkFieldButton.Value = ~app.ReconstructionAmplitudeButton.Value;

            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "off";
            app.ReconstructionPhaseButton.Enable = "off";

            app.PhaseMultiplierLayout.Visible = "off";

            drawnow;

            app.ReconstructionRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
            app.ReconstructionRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

            app.DisplayReconstruction();

            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "on";
            app.ReconstructionPhaseButton.Enable = "on";
        end

        % Value changed function: ReconstructionPhaseButton
        function ReconstructionPhaseButtonValueChanged(app, event)
            app.ReconstructionAmplitudeButton.Value = ~app.ReconstructionPhaseButton.Value;
            app.ReconstructionDarkFieldButton.Value = ~app.ReconstructionPhaseButton.Value;

            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionPhaseButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "off";

            app.PhaseMultiplierLayout.Visible ="on";
            
            drawnow;

            app.ReconstructionRangeStartEditField.Value = num2str(app.PhaseDisplayRange(1));
            app.ReconstructionRangeEndEditField.Value = num2str(app.PhaseDisplayRange(2));

            app.DisplayReconstruction();
            
            app.ReconstructionAmplitudeButton.Enable = "on";
            app.ReconstructionPhaseButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "on";
        end

        % Value changed function: ReconstructionDarkFieldButton
        function ReconstructionDarkFieldButtonValueChanged(app, event)
            app.ReconstructionAmplitudeButton.Value = ~app.ReconstructionDarkFieldButton.Value;
            app.ReconstructionPhaseButton.Value = ~app.ReconstructionDarkFieldButton.Value;

            app.ReconstructionAmplitudeButton.Enable = "off";
            app.ReconstructionPhaseButton.Enable = "off";
            app.ReconstructionDarkFieldButton.Enable = "off";

            app.PhaseMultiplierLayout.Visible = "off";
           
            drawnow;

            app.ReconstructionRangeStartEditField.Value = num2str(app.AmplitudeDisplayRange(1));
            app.ReconstructionRangeEndEditField.Value = num2str(app.AmplitudeDisplayRange(2));

            app.DisplayReconstruction();
            
            app.ReconstructionAmplitudeButton.Enable = "on";
            app.ReconstructionPhaseButton.Enable = "on";
            app.ReconstructionDarkFieldButton.Enable = "off";
        end

        % Value changed function: ReconstructionPhaseMultiplierEditField
        function ReconstructionPhaseMultiplierEditFieldValueChanged(app, event)
            value = app.ReconstructionPhaseMultiplierEditField.Value;
            app.PhaseMultiplierEditField.Value = value;
        end

        % Callback function
        function PhotoWavelengthTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            all=0;
            newWavelengths = app.QCIModel.getWavelengths(all);
            newWavelengths(indices(1)) = newData;

            app.QCIModel.setWavelengths(newWavelengths);
        end

        % Callback function
        function PhotoHeightTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            all = 0;
            newHeights = app.QCIModel.getPropagationDistances(all);
            newHeights(indices(1)) = newData;

            app.QCIModel.setPropagationDistances(newHeights,1:size(app.HologramsRaw,3));
        end

        % Button pushed function: CurrentHeightSubtractButton
        function CurrentHeightSubtractButtonPushed(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value - app.StepEditField.Value;
            app.SweepStartEditField.Value = app.CurrentHeightEditField.Value;          
            %Display app state
            app.ApplicationStateLabel.Text = "Focusing...";
            pause(.05)

            %Model
            app.UpdateModelsPropagationDistances(app.CurrentHeightEditField.Value, app.PassforwardCheckBox.Value, app.CurrentHologramIndex);

            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: CurrentHeightEditField
        function CurrentHeightEditFieldValueChanged(app, event)
            %View
            app.SweepStartEditField.Value = app.CurrentHeightEditField.Value;
            %Display app state
            app.ApplicationStateLabel.Text = "Focusing...";
            pause(.05)

            %Model
            app.UpdateModelsPropagationDistances(app.CurrentHeightEditField.Value, app.PassforwardCheckBox.Value, app.CurrentHologramIndex);
            
            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Button pushed function: CurrentHeightAddButton
        function CurrentHeightAddButtonPushed(app, event)
            %View
            app.CurrentHeightEditField.Value =  app.CurrentHeightEditField.Value + app.StepEditField.Value;
            app.SweepStartEditField.Value = app.CurrentHeightEditField.Value;
            %Display app state
            app.ApplicationStateLabel.Text = "Focusing...";
            pause(.05)
            
            %Model
            app.UpdateModelsPropagationDistances(app.CurrentHeightEditField.Value, app.PassforwardCheckBox.Value, app.CurrentHologramIndex);
            
            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: SweepRangeEditField
        function SweepRangeEditFieldValueChanged(app, event)
            value = app.SweepRangeEditField.Value;

            if(value < app.SweepStepEditField.Value)
                app.SweepStepEditField.Value = app.SweepRangeEditField.Value;
            end
        end

        % Value changed function: SweepStepEditField
        function SweepStepEditFieldValueChanged(app, event)
            value = app.SweepStepEditField.Value;

            if(value > app.SweepRangeEditField.Value)
                app.SweepStepEditField.Value = app.SweepRangeEditField.Value;
            end
        end

        % Button pushed function: AutocorrectButton
        function AutocorrectButtonPushed(app, event)
            all = 0;
            %view
            app.ApplicationStateLabel.Text = "Performing autocorrection...";
            pause(.1)

            if(app.CorrectionModeDropDown.Value == "ShiftAndScaling")
                try
                    [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedPropagationDistances, app.CorrectionTForms] = app.QCIModel.automaticalShiftAndScalingCorrection(app.HologramsPostPropagation);
                catch error
                    popupString =  "Could not perform automatical shift and scaling correction because:"+ newline + error.message + newline +"Try the manual one instead." + newline;
                    popupApp = PopupConfirm(app, popupString);
        
                    while isvalid(popupApp); pause(0.1); end

                    app.ApplicationStateLabel.Text = "Idle";
                    return
                end

                app.DeterminedPropagationDistances = app.QCIModel.getPropagationDistances(all);
                                
                allIndexes =  1:size(app.CorrectedHolograms,3);
                app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                app.QCIModel.setPropagationDistances(app.CorrectedPropagationDistances, allIndexes);
    
                for index = allIndexes 
                    app.CorrectedReference(:,:,index) = app.QCIModel.propagate(index);
                end

                app.QCIModel.setHolograms(app.HologramsRaw, allIndexes);
                app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances, allIndexes);
            elseif(app.CorrectionModeDropDown.Value == "OnlyShift")
                app.CorrectedReference = [];
                app.CorrectedHolograms = [];
                
                try
                    [app.CorrectedReference, rowShifts, columnShifts] = app.QCIModel.automaticalShiftCorrection(app.HologramsPostPropagation);
                catch error
                    Disp("Could not perform automatical shift and scaling correction. Try the manual one.");
                    app.ApplicationStateLabel.Text = "Idle";
                end

                for index = 1:length(rowShifts)-1
                    app.CorrectedHolograms(:,:,index) = app.QCIModel.shiftHologram(app.HologramsRaw(:,:,index), columnShifts(index), rowShifts(index));
                end

                app.CorrectedHolograms(:,:,end+1) = app.HologramsRaw(:,:,end);
            end

            %view
            app.ApplicationStateLabel.Text = "Idle";
        end

        % Button pushed function: ManualcorrectButton
        function ManualcorrectButtonPushed(app, event)
            if(app.IsShiftManualCorrectionOn)
                % Enable controls
                app.EnableMenu();

                app.LayerNumberEditField.Enable = "on";
                app.CropAndPadLeftButton.Enable = "on";
                app.CropAndPadRightButton.Enable = "on";
                app.CropAndPadBottomButton.Enable = "on";
                app.CropAndPadTopButton.Enable = "on";
                app.CropAndPadResetButton.Enable = "on";

                app.ShiftFilesListBox.Enable = "on";
                app.ShiftHologramTypeDropDown.Enable = "on";
                app.AutocorrectButton.Enable = "on";
                app.ShiftPreviousButton.Enable = "on";
                app.ShiftNextButton.Enable = "on";

                if(app.ManualcorrectButton.Text == "Finish correction" && app.CorrectionModeDropDown.Value == "ShiftAndScaling")
                    [app.CorrectedReference, app.CorrectedHolograms, app.CorrectedPropagationDistances, app.CorrectionTForms] = app.QCIModel.threePointShiftAndScalingCorrection(app.HologramsPostPropagation, app.ManualCorrectionXY);
                    
                    all = 0;
                    app.DeterminedPropagationDistances = app.QCIModel.getPropagationDistances(all);
                    
                    allIndexes =  1:size(app.CorrectedHolograms,3);
                    app.QCIModel.setHolograms(app.CorrectedHolograms, allIndexes);
                    app.QCIModel.setPropagationDistances(app.CorrectedPropagationDistances, allIndexes);

                    for index = allIndexes 
                        app.CorrectedReference(:,:,index) = app.QCIModel.propagate(index);
                    end

                    app.QCIModel.setHolograms(app.HologramsRaw, allIndexes);
                    app.QCIModel.setPropagationDistances(app.DeterminedPropagationDistances, allIndexes);
                elseif(app.ManualcorrectButton.Text == "Finish correction" && app.CorrectionModeDropDown.Value == "OnlyShift")
                    referencePoint = app.ManualCorrectionXY{end}{1};

                    for index = 1:size(app.HologramsPostPropagation,3)-1
                        shiftedPoint = app.ManualCorrectionXY{index}{1};
                        differenceBetweenPoints = referencePoint - shiftedPoint;

                        app.CorrectedHolograms(:,:,index) = app.QCIModel.shiftHologram(app.HologramsRaw(:,:,index), differenceBetweenPoints(1), differenceBetweenPoints(2));
                        app.CorrectedReference(:,:,index) = app.QCIModel.shiftHologram(app.HologramsPostPropagation(:,:,index), differenceBetweenPoints(1), differenceBetweenPoints(2));
                    end

                    app.CorrectedHolograms(:,:,end+1) = app.HologramsRaw(:,:, end);
                    app.CorrectedReference(:,:,end+1) = app.HologramsPostPropagation(:,:, end);
                end

                app.CurrentHologramIndex = 1;
                

                %view
                title(app.ShiftUIAxes,"");
                app.ApplicationStateLabel.Text = "Idle";
                app.ManualcorrectButton.Text = "Manual Correction";
                
                app.GotoLabel.Visible = "off";
                app.GoToRed.Visible = "off";
                app.GoToGreen.Visible = "off";
                app.GoToBlue.Visible = "off";
            else
                app.DisableMenu();
                app.ManualCorrectionXY = {};
                
                app.ShiftFilesListBox.Enable = "off";
                app.ShiftHologramTypeDropDown.Enable = "off";
                app.LoadShiftListBox();
                app.ShiftHologramTypeDropDown.Value = 'Reconstruction';
                app.AutocorrectButton.Enable = "off";

                app.LayerNumberEditField.Value = 0;
                app.CropAndPadLeftButton.Value = 0;
                app.CropAndPadRightButton.Value = 0;
                app.CropAndPadBottomButton.Value = 0;
                app.CropAndPadTopButton.Value = 0;

                app.LayerNumberEditField.Enable = "off";
                app.CropAndPadLeftButton.Enable = "off";
                app.CropAndPadRightButton.Enable = "off";
                app.CropAndPadBottomButton.Enable = "off";
                app.CropAndPadTopButton.Enable = "off";
                app.CropAndPadResetButton.Enable = "off";

                app.ShiftPreviousButton.Enable = "off";
                app.ShiftNextButton.Enable = "off";
                
                %view
                app.ApplicationStateLabel.Text = "Performing manual correction";
                
                app.ShiftHologramTypeDropDown.Value = "Reconstruction";
                app.ManualcorrectButton.Text = "Cancel correction";
            end

            app.IsShiftManualCorrectionOn = ~app.IsShiftManualCorrectionOn;
            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: ShiftHologramTypeDropDown
        function ShiftHologramTypeDropDownValueChanged(app, event)
             app.LoadShiftListBox();
             app.ShiftFilesListBox.Value = app.CurrentHologramIndex;
             app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Button pushed function: StartReconstruction
        function StartReconstructionButtonPushed(app, event)
            %View
            app.ApplicationStateLabel.Text = "Performing reconstruction...";
            pause(.1)

            sigma = app.SigmaEditField.Value;
            iterationNumber = app.IterationNumberEditField.Value;
            try
                reconstruction = app.QCIModel.IGA(iterationNumber, sigma);
            catch error
                popupString = "Could not perform the reconstruction because:"+ newline + error.message + newline;
                popupApp = PopupConfirm(app, popupString);
    
                while isvalid(popupApp); pause(0.1); end

                app.ApplicationStateLabel.Text = "Idle";
                return
            end

            %Add a new reconstruction
            app.addReconstruction(reconstruction, sigma, iterationNumber);

            %View
            app.LoadReconstructionListBox();
            app.CurrentHologramIndex = size(app.FinalReconstructions, 3);
            app.ApplicationStateLabel.Text = "Idle";
            app.DisplayReconstruction();
        end

        % Value changed function: CropAndPadTopButton
        function CropAndPadTopButtonValueChanged(app, event)
            value = app.CropAndPadTopButton.Value;

            if(value)
                app.CropAndPadBottomButton.Value = 0;
                app.CropAndPadLeftButton.Value = 0;
                app.CropAndPadRightButton.Value = 0;
                
                app.LayerNumberEditField.Value = app.CropAndPadVector(1);
            else
                app.CropAndPadTopButton.Value = ~value;
            end

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: CropAndPadBottomButton
        function CropAndPadBottomButtonValueChanged(app, event)
            value = app.CropAndPadBottomButton.Value;

            if(value)
                app.CropAndPadTopButton.Value = 0;
                app.CropAndPadLeftButton.Value = 0;
                app.CropAndPadRightButton.Value = 0;
                
                app.LayerNumberEditField.Value = app.CropAndPadVector(2);
            else
                app.CropAndPaBottomButton.Value = ~value;
            end

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: CropAndPadLeftButton
        function CropAndPadLeftButtonValueChanged(app, event)
            value = app.CropAndPadLeftButton.Value;

            if(value)
                app.CropAndPadTopButton.Value = 0;
                app.CropAndPadBottomButton.Value = 0;
                app.CropAndPadRightButton.Value = 0;
                
                app.LayerNumberEditField.Value = app.CropAndPadVector(3);
            else
                app.CropAndPadLeftButton.Value = ~value;
            end

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: CropAndPadRightButton
        function CropAndPadRightButtonValueChanged(app, event)
            value = app.CropAndPadRightButton.Value;

            if(value)
                app.CropAndPadTopButton.Value = 0;
                app.CropAndPadBottomButton.Value = 0;
                app.CropAndPadLeftButton.Value = 0;

                app.LayerNumberEditField.Value = app.CropAndPadVector(4);
            else
                app.CropAndPadRightButton.Value = ~value;
            end

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Button pushed function: CropAndPadResetButton
        function CropAndPadResetButtonPushed(app, event)
            % Reset CropAndPad buttons    
            app.CropAndPadTopButton.Value = 0;
            app.CropAndPadBottomButton.Value = 0;
            app.CropAndPadRightButton.Value = 0;
            app.CropAndPadLeftButton.Value = 0;
            
            % Reset CropAndPadVector
            app.CropAndPadVector = [0, 0, 0, 0];

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Button pushed function: StepSubtractButton
        function StepSubtractButtonValueChanged(app, event)
            if(app.StepEditField.Value/2 > 1)
                app.StepEditField.Value = round(app.StepEditField.Value/2);
            else
                app.StepEditField.Value = 1;
            end
        end

        % Button pushed function: StepAddButton
        function StepAddButtonValueChanged(app, event)
            app.StepEditField.Value = app.StepEditField.Value*2;
        end

        % Button pushed function: MoveAllLeft
        function MoveAllLeftButtonPushed(app, event)
            app.LoadExportResultsAvailableHologramsListBox();

            % Clear exported holograms list box
            app.ExportedHologramsListBox.Items = {};
            app.ExportedHologramsListBox.ItemsData = [];
        end

        % Button pushed function: MoveSingleLeft
        function MoveSingleLeftButtonPushed(app, event)
            [~,selectedExportedIndex] = ismember(app.ExportedHologramsListBox.Value, app.ExportedHologramsListBox.ItemsData);

            app.AvailableHologramsListBox.Items = [app.AvailableHologramsListBox.Items app.ExportedHologramsListBox.Items(selectedExportedIndex)];
            app.AvailableHologramsListBox.ItemsData(end+1) = app.ExportedHologramsListBox.ItemsData(selectedExportedIndex);

            app.ExportedHologramsListBox.Items(selectedExportedIndex) = [];
            app.ExportedHologramsListBox.ItemsData(selectedExportedIndex) = [];

            if(length(app.AvailableHologramsListBox.Items) > 1)
                [app.AvailableHologramsListBox.Items, app.AvailableHologramsListBox.ItemsData] = app.SortListBoxByRisingValue(app.AvailableHologramsListBox);
            end
        end

        % Button pushed function: MoveSingleRight
        function MoveSingleRightButtonPushed(app, event)
            [~,selectedAvailableIndex] = ismember(app.AvailableHologramsListBox.Value, app.AvailableHologramsListBox.ItemsData);

            app.ExportedHologramsListBox.Items = [app.ExportedHologramsListBox.Items app.AvailableHologramsListBox.Items(selectedAvailableIndex)];
            app.ExportedHologramsListBox.ItemsData(end+1) = app.AvailableHologramsListBox.ItemsData(selectedAvailableIndex);
            
            app.AvailableHologramsListBox.Items(selectedAvailableIndex) = [];
            app.AvailableHologramsListBox.ItemsData(selectedAvailableIndex) = [];

            if(length(app.ExportedHologramsListBox.Items) > 1)
                [app.ExportedHologramsListBox.Items, app.ExportedHologramsListBox.ItemsData] = app.SortListBoxByRisingValue(app.ExportedHologramsListBox);
            end
        end

        % Button pushed function: MoveAllRight
        function MoveAllRightButtonPushed(app, event)
            app.LoadExportResultsExportedHologramsListBox();

            % Clear available holograms list box
            app.AvailableHologramsListBox.Items = {};
            app.AvailableHologramsListBox.ItemsData = [];
        end

        % Value changed function: AmplitudeStartValue
        function AmplitudeStartValueValueChanged(app, event)
            app.AmplitudeStartValue.Value = app.EvaluateMathExpression(app.AmplitudeStartValue.Value);
            value = app.EvaluateMathExpression(app.AmplitudeStartValue.Value);

            if(value > str2double(app.AmplitudeEndValue.Value))
                value = str2double(app.AmplitudeEndValue.Value) - 1;
            elseif(value < 0)
                value = 0;
            end

            app.PhaseStartValue.Value = num2str(value);

        end

        % Value changed function: AmplitudeEndValue
        function AmplitudeEndValueValueChanged(app, event)
            value = app.EvaluateMathExpression(app.AmplitudeEndValue.Value);

            if(value < str2double(app.AmplitudeStartValue.Value) && value <= 254)
                value = str2double(app.AmplitudeStartValue.Value) + 1;
            elseif(value > 254)
                value = 255;
            end
            
            app.AmplitudeEndValue.Value = num2str(value);
        end

        % Value changed function: PhaseStartValue
        function PhaseStartValueValueChanged(app, event)
            value = app.EvaluateMathExpression(app.PhaseStartValue.Value);

            if(value > str2double(app.PhaseEndValue.Value))
                value = str2double(app.PhaseEndValue.Value) - 0.01;
            elseif(value < -pi)
                value = -pi;
            end

            app.PhaseStartValue.Value = num2str(value);
        end

        % Value changed function: PhaseEndValue
        function PhaseEndValueValueChanged(app, event)
            value = app.EvaluateMathExpression(app.PhaseEndValue.Value);
            
            if(value > pi)
                value = pi;
            elseif(value < str2double(app.PhaseStartValue.Value) && value <= 3.13)
                value = str2double(app.PhaseStartValue.Value) + 0.01;
            elseif(value > 3.13)
                value = pi;
            end

            app.PhaseEndValue.Value = num2str(value);
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            app.ApplicationStateLabel.Text = "Exporting data...";
            pathToFiles = app.Files(1).folder;
            currentDate = app.SessionStartDateTime;
            pathToResultsFolder = pathToFiles + "\Results_" + currentDate;
            mkdir(pathToResultsFolder);
            pause(.1);

            try
                app.ExportHolograms(pathToResultsFolder);
            catch error
                popupString = "Could not export data because:"+ newline + error.message + newline;
                popupApp = PopupConfirm(app, popupString);

                while isvalid(popupApp); pause(0.1); end
            end

            if(app.ExportreconstructiondataCheckBox.Value)
                try
                    app.ExportReconstructionData(pathToResultsFolder);
                catch error
                    popupString = "Could not save the ResultsData.mat because:"+ newline + error.message + newline;
                    popupApp = PopupConfirm(app, popupString);
        
                    while isvalid(popupApp); pause(0.1); end
                end
            end

            popupString = "Data exported successfully.";
            popupApp = PopupConfirm(app, popupString);

            while isvalid(popupApp); pause(0.1); end

            app.ApplicationStateLabel.Text = "Idle";
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            start = app.SweepStartEditField.Value;
            range = app.SweepRangeEditField.Value;
            step = app.SweepStepEditField.Value;

            % Y axis faces down, X axis faces right
            xlim = round(app.FocusUIAxes.XLim);
            ylim = round(app.FocusUIAxes.YLim);
            
            % display app state
            app.ApplicationStateLabel.Text = "Performing dark focus...";
            pause(.1)

            %test execution time
            tic
            trialRange = start:range:start+range;
            ROI = [xlim(1), ylim(1), xlim(2)-xlim(1), ylim(2)-ylim(1)];
            darkVolumeROI = app.FocusROIRectanglePosition;
            app.QCIModel.DarkFocus(app.CurrentHologramIndex, trialRange, ROI, darkVolumeROI, app.FocusAmplitudeButton.Value);
            timeSeconds = toc;

            % Check if the estimate execution time is reasonable
            darkFocusRange = start-range:step:start+range;
            if(length(darkFocusRange)*timeSeconds > 60)
                popupApp = PopupYesNo(app, "Dark focus will last " + num2str(length(darkFocusRange)*timeSeconds/60, 1) ...
                                            + " minutes " + num2str(mod(timeSeconds,60),2) + " seconds." ...
                                            + newline +"Do you wish to continue?");

                while isvalid(popupApp); pause(0.1); end
                
                if(~app.PopupStatus)
                    app.ApplicationStateLabel.Text = "Idle";
                    pause(0.1)
                    return
                end
            end

            % perform dark focus
            ROI = [xlim(1), ylim(1), xlim(2)-xlim(1), ylim(2)-ylim(1)];
            darkVolumeROI = app.FocusROIRectanglePosition;
            [betterZ, focusCurves,~] = app.QCIModel.DarkFocus(app.CurrentHologramIndex, darkFocusRange, ROI, darkVolumeROI, app.FocusAmplitudeButton.Value);

            %Model
            app.UpdateModelsPropagationDistances(betterZ, app.PassforwardCheckBox.Value, app.CurrentHologramIndex);

            app.CurrentHeightEditField.Value = betterZ;
            app.SweepStartEditField.Value = betterZ;

            showFocusCurveOn = app.ShowfocuscurveCheckBox.Value;

            if(showFocusCurveOn)
                % Find best focus point
                [bestMetricValue, bestIndex] = max(focusCurves);
                bestZ = darkFocusRange(bestIndex);
            
                figureTag = "HoloApp_DarkFocusCurve";
                existingFigure = findall(0, "Type", "figure", "Tag", figureTag);
            
                if isempty(existingFigure) || ~isvalid(existingFigure)
                    focusFigure = figure( ...
                        "Name", "Dark-focus curve", ...
                        "NumberTitle", "off", ...
                        "Color", "w", ...
                        "Tag", figureTag);
                else
                    focusFigure = existingFigure(1);
                    figure(focusFigure); %#ok<FIGURE> bring to front
                end
            
                axHandle = gca(focusFigure);
                cla(axHandle);
                hold(axHandle, "on");
            
                plot(axHandle, darkFocusRange, focusCurves, "LineWidth", 1.5);
                plot(axHandle, bestZ, bestMetricValue, "o", "MarkerSize", 7, "LineWidth", 1.5);
            
                xline(axHandle, bestZ, "--", sprintf("Best z = %.2f \\mum", bestZ), ...
                    "LabelHorizontalAlignment", "left", ...
                    "LabelVerticalAlignment", "middle");
            
                xlabel(axHandle, "Propagation distance z [\mum]");
                ylabel(axHandle, "Normalized focus metric");
                title(axHandle, "Dark-focus curve");
                grid(axHandle, "on");
                box(axHandle, "on");
            
                hold(axHandle, "off");
            end
            
            app.DisplayFocus();
            app.LoadModeTableData();
        end

        % Value changed function: InspectROICheckBox
        function InspectROICheckBoxValueChanged(app, event)
            if app.InspectROICheckBox.Value
                % --- ENABLED ---
                app.ApplicationStateLabel.Text = "Selecting ROI...";
                
                % 1. Create Rectangle
                % If Position is 0 (because we reset it), this triggers a NEW drawing session.
                if any(app.FocusROIRectanglePosition ~= 0)
                    % Restore existing (Only happens if they uncheck/check manually without zooming)
                    app.FocusROIRectangleObject = drawrectangle(app.FocusUIAxes, ...
                        'Color', 'b', 'Position', app.FocusROIRectanglePosition);
                else
                    % User must draw a NEW one
                    app.FocusROIRectangleObject = drawrectangle(app.FocusUIAxes, 'Color', 'b');
                    % Save the new position immediately
                    app.FocusROIRectanglePosition = app.FocusROIRectangleObject.Position;
                end
                
                % 2. Add Listeners
                addlistener(app.FocusROIRectangleObject, 'ROIMoved', @app.FocusROIUpdateRectangleListener);
                
                % Start the Zoom Watchdog
                if isempty(app.AxisZoomListener)
                    app.AxisZoomListener = addlistener(app.FocusUIAxes, {'XLim', 'YLim'}, ...
                        'PostSet', @(src,evt)app.CheckROIVisibility);
                end
                
            else
                % --- DISABLED MANUALLY ---
                app.ApplicationStateLabel.Text = "ROI Inspection Off";
                
                % Just cleanup visuals, but DO NOT reset position to 0 here.
                % (This allows the user to toggle the box on/off manually without losing their work)
                if isvalid(app.FocusROIRectangleObject)
                    delete(app.FocusROIRectangleObject);
                end
                
                if ~isempty(app.AxisZoomListener)
                    delete(app.AxisZoomListener);
                    app.AxisZoomListener = [];
                end
            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            popupApp = PopupYesNo(app, "Do you wish to save the current session?");

            while isvalid(popupApp); pause(0.1); end
            
            if(app.PopupStatus)
                savingSessionLabel = "Saving current session.";
                app.ApplicationStateLabel.Text = savingSessionLabel;

                app.DisableInterface();

                app.SaveAppState();

                emptyLabel = "";
                app.ApplicationStateLabel.Text = emptyLabel;
                app.EnableInterface();
            end
        end

        % Value changed function: GPUProcessingCheckBox
        function GPUProcessingCheckBoxValueChanged(app, event)
            app.GPUProcessingStatus = app.GPUProcessingCheckBox.Value;

            all = 0;
            holograms = app.QCIModel.getHolograms(all);

            if(~isempty(holograms))
                names = app.QCIModel.getNames(all);
                wavelengths = app.QCIModel.getWavelengths(all);
                zCoordiantes = app.QCIModel.getPropagationDistances(all);
                pixelSize = app.PixelSizeEditField.Value;

                if(app.GPUProcessingStatus)
                    app.QCIModel = QCI_Model_GPU(names, holograms, wavelengths, zCoordiantes, pixelSize); 
                else
                    app.QCIModel = QCI_Model(names, holograms, wavelengths, zCoordiantes, pixelSize); 
                end
            end
        end

        % Close request function: HoLLoAppUIFigure
        function HoLLoAppUIFigureCloseRequest(app, event)
            % 1. Check if the popup is already open
            if ~isempty(app.ClosePopupHandle) && isvalid(app.ClosePopupHandle)
                % The popup exists! Bring it to the front to remind the user it's there
                return; % Exit this callback immediately so we don't spawn another one
            end
            
            % 2. Open the popup and save its handle to our property
            app.ClosePopupHandle = PopupYesNo(app, "Do you wish to save the current session?");
            
            % 3. Wait for the popup to be closed
            while isvalid(app.ClosePopupHandle) 
                pause(0.1); 
            end
            
            % 4. Handle the save logic
            if(app.PopupStatus)
                app.SaveAppState();
            end
            
            % 5. Clean up other sub-apps
            if ~isempty(app.HoloPrepHandle) && isvalid(app.HoloPrepHandle)
                delete(app.HoloPrepHandle);
            end
            
            % 6. Finally, close the main app
            delete(app);
        end

        % Value changed function: LayerNumberEditField
        function LayerNumberEditFieldValueChanged(app, event)
            cropAndPadValue = app.LayerNumberEditField.Value;

            if app.CropAndPadTopButton.Value
                app.CropAndPadVector(1) = cropAndPadValue;
            elseif app.CropAndPadBottomButton.Value
                app.CropAndPadVector(2) = cropAndPadValue;
            elseif app.CropAndPadLeftButton.Value
                app.CropAndPadVector(3) = cropAndPadValue;
            elseif app.CropAndPadRightButton.Value
                app.CropAndPadVector(4) = cropAndPadValue;
            end

            app.DisplayShift(app.IsShiftManualCorrectionOn);
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            if(isempty(app.HologramsRaw))
                return
            end
            
            value = app.WavelengthEditField.Value;
            imageNumber = size(app.HologramsRaw,3);
            wavelengths = ones(1,imageNumber)*value;

            app.QCIModel.setWavelengths(wavelengths);

            app.LoadModeTableData();
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            if(isempty(app.HologramsRaw))
                return
            end
            
            value = app.HeightEditField.Value;
            imageNumber = size(app.HologramsRaw,3);
            propagationDistances = ones(1,imageNumber)*value;

            app.QCIModel.setPropagationDistances(propagationDistances);

            app.LoadModeTableData();
        end

        % Value changed function: PixelSizeEditField
        function PixelSizeEditFieldValueChanged(app, event)
            if(isempty(app.HologramsRaw))
                return
            end
            
            value = app.PixelSizeEditField.Value;
            app.QCIModel.setCameraPixelSize(value);
        end

        % Value changed function: PhaseMultiplierEditField
        function PhaseMultiplierEditFieldValueChanged(app, event)
            value = app.PhaseMultiplierEditField.Value;
            valueAsNumber = app.EvaluateMathExpression(value);

            app.PhaseMultiplierEditField.Value = num2str(valueAsNumber);
            app.ReconstructionPhaseMultiplierEditField.Value = num2str(valueAsNumber);

            app.DisplayReconstruction();
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            formatString = app.FormatDropDown.Value;
            exportedFormats = app.ExportedFormatsEditField.Value;

            if(contains(exportedFormats, formatString))
                app.AddFormatButton.Enable = "off";
                app.DeleteFormatButton.Enable = "on";
            else
                app.AddFormatButton.Enable = "on";
                app.DeleteFormatButton.Enable = "off";
            end
        end

        % Button pushed function: AddFormatButton
        function AddFormatButtonPushed(app, event)
            exportedFormats = app.ExportedFormatsEditField.Value;
            formatString = app.FormatDropDown.Value;
            separator = ';';

            if(contains(exportedFormats, formatString))
                app.AddFormatButton.Enable = "off";
            else
                exportedFormats = strcat(exportedFormats, formatString, separator);
                app.ExportedFormatsEditField.Value = exportedFormats;

                app.AddFormatButton.Enable = "off";
                app.DeleteFormatButton.Enable = "on";
            end
        end

        % Button pushed function: DeleteFormatButton
        function DeleteFormatButtonPushed(app, event)
            exportedFormats = app.ExportedFormatsEditField.Value;
            formatString = app.FormatDropDown.Value;
            separator = ';';

            if(contains(exportedFormats, formatString) && count(exportedFormats, separator) > 1)
                app.AddFormatButton.Enable = "off";
                app.ExportedFormatsEditField.Value = erase(exportedFormats, strcat(formatString, separator));

                app.AddFormatButton.Enable = "on";
                app.DeleteFormatButton.Enable = "off";
            else
                app.DeleteFormatButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HoLLoAppUIFigure = uifigure('Visible', 'off');
            app.HoLLoAppUIFigure.Name = 'HoLLoApp';
            app.HoLLoAppUIFigure.Icon = fullfile(pathToMLAPP, 'data', 'AppLogo_noBackground.png');
            app.HoLLoAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @HoLLoAppUIFigureCloseRequest, true);
            app.BackLayout = uigridlayout(app.HoLLoAppUIFigure);
            app.SideLayout = uigridlayout(app.BackLayout);
            app.MainLayout = uigridlayout(app.SideLayout);
            app.NavigationTab = uitabgroup(app.MainLayout);
            app.NavigationTab.SelectionChangedFcn = createCallbackFcn(app, @NavigationTabSelectionChanged, true);
            app.LoadDataTab = uitab(app.NavigationTab);
            app.LoadDataTab.Title = 'Load Data';
            app.LoadDataLayout = uigridlayout(app.LoadDataTab);
            app.LoadDataControlLayout = uigridlayout(app.LoadDataLayout);
            app.LoadHologramsButton = uibutton(app.LoadDataControlLayout, 'push');
            app.LoadHologramsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsButtonPushed, true);
            app.LoadHologramsButton.Text = 'Load Holograms';
            app.LoadDataFileListBoxLayout = uigridlayout(app.LoadDataControlLayout);
            app.HologramsLabel = uilabel(app.LoadDataFileListBoxLayout);
            app.HologramsLabel.Text = 'Holograms';
            app.LoadDataFilesListBox = uilistbox(app.LoadDataFileListBoxLayout);
            app.LoadDataFilesListBox.Items = {''};
            app.LoadDataFilesListBox.ValueChangedFcn = createCallbackFcn(app, @LoadDataFilesListBoxValueChanged, true);
            app.LoadDataFilesListBox.Value = {};
            app.DefaultValuesLayout = uigridlayout(app.LoadDataControlLayout);
            app.DefaultvaluesLabel = uilabel(app.DefaultValuesLayout);
            app.DefaultvaluesLabel.Text = 'Default values';
            app.PixelSizemLabel = uilabel(app.DefaultValuesLayout);
            app.PixelSizemLabel.Text = 'Pixel Size    [μm]';
            app.PixelSizeEditField = uieditfield(app.DefaultValuesLayout, 'numeric');
            app.PixelSizeEditField.Limits = [0.01 Inf];
            app.PixelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeEditFieldValueChanged, true);
            app.PixelSizeEditField.Value = 2.4;
            app.WavelengthmLabel = uilabel(app.DefaultValuesLayout);
            app.WavelengthmLabel.Text = 'Wavelength [μm]';
            app.WavelengthEditField = uieditfield(app.DefaultValuesLayout, 'numeric');
            app.WavelengthEditField.Limits = [0 Inf];
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Value = 0.561;
            app.HeightmLabel = uilabel(app.DefaultValuesLayout);
            app.HeightmLabel.Text = 'Height          [μm]';
            app.HeightEditField = uieditfield(app.DefaultValuesLayout, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.ModeDataLayout = uigridlayout(app.LoadDataControlLayout);
            app.ModeLabel = uilabel(app.ModeDataLayout);
            app.ModeLabel.Text = 'Mode';
            app.ModeDropDownLabel = uilabel(app.ModeDataLayout);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ModeDataLayout);
            app.ModeDropDown.Items = {'Single hologram', 'Multi-wavelenght', 'Multi-height'};
            app.ModeDropDown.ItemsData = {'Single hologram', 'Multi-wavelenght', 'Multi-height'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Single hologram';
            app.ModeTabGroup = uitabgroup(app.ModeDataLayout);
            app.MultiwavelenghtTab = uitab(app.ModeTabGroup);
            app.MultiwavelenghtTab.Title = 'Multi-wavelenght';
            app.MultiwavelengthLayout = uigridlayout(app.MultiwavelenghtTab);
            app.PhotoWavelengthTable = uitable(app.MultiwavelengthLayout);
            app.PhotoWavelengthTable.ColumnName = {'Photo'; 'Wavelength [μm]'};
            app.PhotoWavelengthTable.RowName = {};
            app.MultiheightTab = uitab(app.ModeTabGroup);
            app.MultiheightTab.Title = 'Multi-height';
            app.MultiheightLayout = uigridlayout(app.MultiheightTab);
            app.PhotoHeightTable = uitable(app.MultiheightLayout);
            app.PhotoHeightTable.ColumnName = {'Photo'; 'Height [μm]'};
            app.PhotoHeightTable.RowName = {};
            app.LoadDataDisplayLayout = uigridlayout(app.LoadDataLayout);
            app.LoadDataUIAxes = uiaxes(app.LoadDataDisplayLayout);
            app.LoadDataUIAxes.XTick = [];
            app.LoadDataUIAxes.YTick = [];
            app.LoadDataUIAxes.ZTick = [];
            app.LoadDataDisplayButtonsLayout = uigridlayout(app.LoadDataDisplayLayout);
            app.LoadDataNextButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.LoadDataNextButton.Text = '>';
            app.LoadDataPreviousButton = uibutton(app.LoadDataDisplayButtonsLayout, 'push');
            app.LoadDataPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.LoadDataPreviousButton.Text = '<';
            app.LoadDataGraphSettingsLayout = uigridlayout(app.LoadDataDisplayLayout);
            app.LoadDataRangeEndEditField = uieditfield(app.LoadDataGraphSettingsLayout, 'text');
            app.LoadDataRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.LoadDataRangeEndEditField.Value = '255';
            app.ValuerangeLabel = uilabel(app.LoadDataGraphSettingsLayout);
            app.ValuerangeLabel.Text = 'Value range:';
            app.LoadDataRangeStartEditField = uieditfield(app.LoadDataGraphSettingsLayout, 'text');
            app.LoadDataRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.LoadDataRangeStartEditField.Value = '0';
            app.Label_3 = uilabel(app.LoadDataGraphSettingsLayout);
            app.Label_3.Text = '-';
            app.FocusTab = uitab(app.NavigationTab);
            app.FocusTab.Title = 'Focus';
            app.FocusLayout = uigridlayout(app.FocusTab);
            app.FocusControlLayout = uigridlayout(app.FocusLayout);
            app.FocusingPanel = uipanel(app.FocusControlLayout);
            app.AdjustFocusLayout = uigridlayout(app.FocusingPanel);
            app.AdjustFocusLabel = uilabel(app.AdjustFocusLayout);
            app.AdjustFocusLabel.Text = 'Adjust Focus';
            app.StepControlLayout = uigridlayout(app.AdjustFocusLayout);
            app.StepLabel = uilabel(app.StepControlLayout);
            app.StepLabel.Text = 'Step [μm]';
            app.StepEditField = uieditfield(app.StepControlLayout, 'numeric');
            app.StepEditField.Limits = [1 Inf];
            app.StepEditField.Value = 100;
            app.StepSubtractButton = uibutton(app.StepControlLayout, 'push');
            app.StepSubtractButton.ButtonPushedFcn = createCallbackFcn(app, @StepSubtractButtonValueChanged, true);
            app.StepSubtractButton.Text = '-';
            app.StepAddButton = uibutton(app.StepControlLayout, 'push');
            app.StepAddButton.ButtonPushedFcn = createCallbackFcn(app, @StepAddButtonValueChanged, true);
            app.StepAddButton.Text = '+';
            app.CurrentHeightControlLayout = uigridlayout(app.AdjustFocusLayout);
            app.CurrentHeightmLabel = uilabel(app.CurrentHeightControlLayout);
            app.CurrentHeightmLabel.Text = 'Current Height [μm]';
            app.CurrentHeightEditField = uieditfield(app.CurrentHeightControlLayout, 'numeric');
            app.CurrentHeightEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentHeightEditFieldValueChanged, true);
            app.CurrentHeightSubtractButton = uibutton(app.CurrentHeightControlLayout, 'push');
            app.CurrentHeightSubtractButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentHeightSubtractButtonPushed, true);
            app.CurrentHeightSubtractButton.Text = '-';
            app.CurrentHeightAddButton = uibutton(app.CurrentHeightControlLayout, 'push');
            app.CurrentHeightAddButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentHeightAddButtonPushed, true);
            app.CurrentHeightAddButton.Text = '+';
            app.SweepAutoFocusLayout = uigridlayout(app.AdjustFocusLayout);
            app.SweepStepEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepStepEditField.Limits = [0.01 Inf];
            app.SweepStepEditField.ValueChangedFcn = createCallbackFcn(app, @SweepStepEditFieldValueChanged, true);
            app.SweepStepEditField.Value = 1;
            app.SweepStepLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepStepLabel.Text = 'Step [μm]';
            app.SweepRangeEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.SweepRangeEditField.Limits = [0.01 Inf];
            app.SweepRangeEditField.ValueChangedFcn = createCallbackFcn(app, @SweepRangeEditFieldValueChanged, true);
            app.SweepRangeEditField.Value = 10;
            app.SweepRangeLabel = uilabel(app.SweepAutoFocusLayout);
            app.SweepRangeLabel.Text = 'Range [μm]';
            app.DarkfocusLabel = uilabel(app.SweepAutoFocusLayout);
            app.DarkfocusLabel.Text = 'Dark focus';
            app.StartmLabel = uilabel(app.SweepAutoFocusLayout);
            app.StartmLabel.Text = 'Start [μm]';
            app.SweepStartEditField = uieditfield(app.SweepAutoFocusLayout, 'numeric');
            app.AutofocusButton = uibutton(app.SweepAutoFocusLayout, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Auto focus';
            app.InspectROICheckboxLayout = uigridlayout(app.AdjustFocusLayout);
            app.InspectROICheckBox = uicheckbox(app.InspectROICheckboxLayout);
            app.InspectROICheckBox.ValueChangedFcn = createCallbackFcn(app, @InspectROICheckBoxValueChanged, true);
            app.InspectROICheckBox.Text = 'Inspect ROI';
            app.ShowfocuscurveCheckboxLayout = uigridlayout(app.AdjustFocusLayout);
            app.ShowfocuscurveCheckBox = uicheckbox(app.ShowfocuscurveCheckboxLayout);
            app.ShowfocuscurveCheckBox.Text = 'Show focus curve';
            app.PassforwardCheckboxLayout = uigridlayout(app.AdjustFocusLayout);
            app.PassforwardCheckBox = uicheckbox(app.PassforwardCheckboxLayout);
            app.PassforwardCheckBox.Text = 'Pass forward';
            app.PassforwardCheckBox.Value = true;
            app.FocusHeightTable = uitable(app.AdjustFocusLayout);
            app.FocusHeightTable.ColumnName = {'Photo'; 'Height [μm]'};
            app.FocusHeightTable.RowName = {};
            app.FocusFilesPanel = uipanel(app.FocusControlLayout);
            app.FocusFileListBoxLayout = uigridlayout(app.FocusFilesPanel);
            app.HologramsLabel_2 = uilabel(app.FocusFileListBoxLayout);
            app.HologramsLabel_2.Text = 'Holograms';
            app.FocusFilesListBox = uilistbox(app.FocusFileListBoxLayout);
            app.FocusFilesListBox.Items = {};
            app.FocusFilesListBox.ValueChangedFcn = createCallbackFcn(app, @FocusFilesListBoxValueChanged, true);
            app.FocusFilesListBox.Value = {};
            app.FocusDisplayLayout = uigridlayout(app.FocusLayout);
            app.FocusUIAxes = uiaxes(app.FocusDisplayLayout);
            app.FocusUIAxes.XTick = [];
            app.FocusUIAxes.YTick = [];
            app.FocusUIAxes.ZTick = [];
            app.FocusDisplayButtonsLayout = uigridlayout(app.FocusDisplayLayout);
            app.FocusNextButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.FocusNextButton.Text = '>';
            app.FocusPreviousButton = uibutton(app.FocusDisplayButtonsLayout, 'push');
            app.FocusPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.FocusPreviousButton.Text = '<';
            app.FocusPhaseButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.FocusPhaseButton.ValueChangedFcn = createCallbackFcn(app, @FocusPhaseButtonValueChanged, true);
            app.FocusPhaseButton.Text = 'Phase';
            app.FocusAmplitudeButton = uibutton(app.FocusDisplayButtonsLayout, 'state');
            app.FocusAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @FocusAmplitudeButtonValueChanged, true);
            app.FocusAmplitudeButton.Text = 'Amplitude';
            app.FocusAmplitudeButton.Value = true;
            app.FocusGraphSettingsLayout = uigridlayout(app.FocusDisplayLayout);
            app.FocusRangeLabel = uilabel(app.FocusGraphSettingsLayout);
            app.FocusRangeLabel.Text = 'Value range:';
            app.FocusRangeStartEditField = uieditfield(app.FocusGraphSettingsLayout, 'text');
            app.FocusRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.FocusRangeStartEditField.Value = '0';
            app.Label_4 = uilabel(app.FocusGraphSettingsLayout);
            app.Label_4.Text = '-';
            app.FocusRangeEndEditField = uieditfield(app.FocusGraphSettingsLayout, 'text');
            app.FocusRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.FocusRangeEndEditField.Value = '255';
            app.ShiftTab = uitab(app.NavigationTab);
            app.ShiftTab.Title = 'Shift';
            app.ShiftLayout = uigridlayout(app.ShiftTab);
            app.ShiftControlLayout = uigridlayout(app.ShiftLayout);
            app.CorrectShiftLayout = uigridlayout(app.ShiftControlLayout);
            app.XYTable = uitable(app.CorrectShiftLayout);
            app.XYTable.ColumnName = {'X'; 'Y'};
            app.XYTable.RowName = {};
            app.ManualcorrectButton = uibutton(app.CorrectShiftLayout, 'push');
            app.ManualcorrectButton.ButtonPushedFcn = createCallbackFcn(app, @ManualcorrectButtonPushed, true);
            app.ManualcorrectButton.Text = 'Manual correct';
            app.AutocorrectButton = uibutton(app.CorrectShiftLayout, 'push');
            app.AutocorrectButton.ButtonPushedFcn = createCallbackFcn(app, @AutocorrectButtonPushed, true);
            app.AutocorrectButton.Text = 'Auto correct';
            app.CorrectionModeDropDownLayout = uigridlayout(app.CorrectShiftLayout);
            app.CorrectionModeDropDown = uidropdown(app.CorrectionModeDropDownLayout);
            app.CorrectionModeDropDown.Items = {'Shift and Scaling', 'Only Shift'};
            app.CorrectionModeDropDown.ItemsData = {'ShiftAndScaling', 'OnlyShift'};
            app.CorrectionModeDropDown.Value = 'ShiftAndScaling';
            app.CorrectShiftLabel = uilabel(app.CorrectShiftLayout);
            app.CorrectShiftLabel.Text = 'Correct Shift';
            app.CropAndPadWithPaddingLayout = uigridlayout(app.CorrectShiftLayout);
            app.CropAndPadButtonsLayout = uigridlayout(app.CropAndPadWithPaddingLayout);
            app.CropAndPadLeftButton = uibutton(app.CropAndPadButtonsLayout, 'state');
            app.CropAndPadLeftButton.ValueChangedFcn = createCallbackFcn(app, @CropAndPadLeftButtonValueChanged, true);
            app.CropAndPadLeftButton.Icon = fullfile(pathToMLAPP, 'data', 'Icons', 'PadLeft.svg');
            app.CropAndPadLeftButton.Text = '';
            app.CropAndPadBottomButton = uibutton(app.CropAndPadButtonsLayout, 'state');
            app.CropAndPadBottomButton.ValueChangedFcn = createCallbackFcn(app, @CropAndPadBottomButtonValueChanged, true);
            app.CropAndPadBottomButton.Icon = fullfile(pathToMLAPP, 'data', 'Icons', 'PadBottom.svg');
            app.CropAndPadBottomButton.Text = '';
            app.CropAndPadRightButton = uibutton(app.CropAndPadButtonsLayout, 'state');
            app.CropAndPadRightButton.ValueChangedFcn = createCallbackFcn(app, @CropAndPadRightButtonValueChanged, true);
            app.CropAndPadRightButton.Icon = fullfile(pathToMLAPP, 'data', 'Icons', 'PadRight.svg');
            app.CropAndPadRightButton.Text = '';
            app.CropAndPadTopButton = uibutton(app.CropAndPadButtonsLayout, 'state');
            app.CropAndPadTopButton.ValueChangedFcn = createCallbackFcn(app, @CropAndPadTopButtonValueChanged, true);
            app.CropAndPadTopButton.Icon = fullfile(pathToMLAPP, 'data', 'Icons', 'PadTop.svg');
            app.CropAndPadTopButton.Text = '';
            app.CropAndPadResetButton = uibutton(app.CropAndPadButtonsLayout, 'push');
            app.CropAndPadResetButton.ButtonPushedFcn = createCallbackFcn(app, @CropAndPadResetButtonPushed, true);
            app.CropAndPadResetButton.Icon = fullfile(pathToMLAPP, 'data', 'Icons', 'PadNothing.svg');
            app.CropAndPadResetButton.Text = '';
            app.CropAndPadLayerNumberLayout = uigridlayout(app.CropAndPadWithPaddingLayout);
            app.LayerNumberEditFieldLabel = uilabel(app.CropAndPadLayerNumberLayout);
            app.LayerNumberEditFieldLabel.Text = ' Layer Number';
            app.LayerNumberEditField = uieditfield(app.CropAndPadLayerNumberLayout, 'numeric');
            app.LayerNumberEditField.ValueChangedFcn = createCallbackFcn(app, @LayerNumberEditFieldValueChanged, true);
            app.CropandPadLabel = uilabel(app.CropAndPadWithPaddingLayout);
            app.CropandPadLabel.Text = 'Crop and Pad';
            app.ShiftHologramTypeDropDown = uidropdown(app.ShiftControlLayout);
            app.ShiftHologramTypeDropDown.Items = {'Reconstruction', 'Holograms', 'Shifted Reconstruction', 'Shifted Holograms'};
            app.ShiftHologramTypeDropDown.ItemsData = {'Reconstruction', 'Holograms', 'Shifted Reconstruction', 'Shifted Holograms'};
            app.ShiftHologramTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShiftHologramTypeDropDownValueChanged, true);
            app.ShiftHologramTypeDropDown.Value = 'Reconstruction';
            app.ShiftFileListBoxLayout = uigridlayout(app.ShiftControlLayout);
            app.FilesListBox_3Label_2 = uilabel(app.ShiftFileListBoxLayout);
            app.FilesListBox_3Label_2.Text = 'Holograms';
            app.ShiftFilesListBox = uilistbox(app.ShiftFileListBoxLayout);
            app.ShiftFilesListBox.Items = {''};
            app.ShiftFilesListBox.ValueChangedFcn = createCallbackFcn(app, @ShiftFilesListBoxValueChanged, true);
            app.ShiftFilesListBox.Value = {};
            app.ShiftDisplayLayout = uigridlayout(app.ShiftLayout);
            app.ShiftUIAxes = uiaxes(app.ShiftDisplayLayout);
            app.ShiftUIAxes.XTick = [];
            app.ShiftUIAxes.YTick = [];
            app.ShiftUIAxes.ZTick = [];
            app.ShiftDisplayButtonsLayout = uigridlayout(app.ShiftDisplayLayout);
            app.ShiftNextButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.ShiftNextButton.Text = '>';
            app.ShiftPreviousButton = uibutton(app.ShiftDisplayButtonsLayout, 'push');
            app.ShiftPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.ShiftPreviousButton.Text = '<';
            app.ShiftPhaseButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftPhaseButton.ValueChangedFcn = createCallbackFcn(app, @ShiftPhaseButtonValueChanged, true);
            app.ShiftPhaseButton.Text = 'Phase';
            app.ShiftAmplitudeButton = uibutton(app.ShiftDisplayButtonsLayout, 'state');
            app.ShiftAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @ShiftAmplitudeButtonValueChanged, true);
            app.ShiftAmplitudeButton.Text = 'Amplitude';
            app.ShiftAmplitudeButton.Value = true;
            app.ShiftGrahpSettingsLayout = uigridlayout(app.ShiftDisplayLayout);
            app.GoToRed = uibutton(app.ShiftGrahpSettingsLayout, 'push');
            app.GoToRed.ButtonPushedFcn = createCallbackFcn(app, @GoToRedButtonPushed, true);
            app.GoToRed.Visible = 'off';
            app.GoToRed.Text = '';
            app.GoToGreen = uibutton(app.ShiftGrahpSettingsLayout, 'push');
            app.GoToGreen.ButtonPushedFcn = createCallbackFcn(app, @GoToGreenButtonPushed, true);
            app.GoToGreen.Visible = 'off';
            app.GoToGreen.Text = '';
            app.GoToBlue = uibutton(app.ShiftGrahpSettingsLayout, 'push');
            app.GoToBlue.ButtonPushedFcn = createCallbackFcn(app, @GoToBlueButtonPushed, true);
            app.GoToBlue.Visible = 'off';
            app.GoToBlue.Text = '';
            app.GotoLabel = uilabel(app.ShiftGrahpSettingsLayout);
            app.GotoLabel.Visible = 'off';
            app.GotoLabel.Text = 'Go to:';
            app.ShiftRangeLabel = uilabel(app.ShiftGrahpSettingsLayout);
            app.ShiftRangeLabel.Text = 'Value range:';
            app.ShiftRangeStartEditField = uieditfield(app.ShiftGrahpSettingsLayout, 'text');
            app.ShiftRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.ShiftRangeStartEditField.Value = '0';
            app.Label_5 = uilabel(app.ShiftGrahpSettingsLayout);
            app.Label_5.Text = '-';
            app.ShiftRangeEndEditField = uieditfield(app.ShiftGrahpSettingsLayout, 'text');
            app.ShiftRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.ShiftRangeEndEditField.Value = '255';
            app.ReconstructionTab = uitab(app.NavigationTab);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.ReconstructionLayout = uigridlayout(app.ReconstructionTab);
            app.ReconstructionControlLayout = uigridlayout(app.ReconstructionLayout);
            app.ReconstructionSettingsLayout = uigridlayout(app.ReconstructionControlLayout);
            app.StartReconstruction = uibutton(app.ReconstructionSettingsLayout, 'push');
            app.StartReconstruction.ButtonPushedFcn = createCallbackFcn(app, @StartReconstructionButtonPushed, true);
            app.StartReconstruction.Text = 'Start';
            app.ReconstructionParametersLayout = uigridlayout(app.ReconstructionSettingsLayout);
            app.Label_2 = uilabel(app.ReconstructionParametersLayout);
            app.Label_2.Text = 'σ - parameter';
            app.IterationNumberEditFieldLabel = uilabel(app.ReconstructionParametersLayout);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.ReconstructionParametersLayout, 'numeric');
            app.IterationNumberEditField.Limits = [1 Inf];
            app.IterationNumberEditField.RoundFractionalValues = 'on';
            app.IterationNumberEditField.ValueDisplayFormat = '%.0f';
            app.IterationNumberEditField.Value = 5;
            app.SigmaEditField = uieditfield(app.ReconstructionParametersLayout, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.ReconstructionsettingsLabel = uilabel(app.ReconstructionSettingsLayout);
            app.ReconstructionsettingsLabel.Text = 'Reconstruction settings';
            app.PhaseMultiplierLayout = uigridlayout(app.ReconstructionSettingsLayout);
            app.PhaseMultiplierLayout.Visible = 'off';
            app.PhaseMultiplierLabel = uilabel(app.PhaseMultiplierLayout);
            app.PhaseMultiplierLabel.Text = 'Phase Multiplier';
            app.PhaseMultiplierEditField = uieditfield(app.PhaseMultiplierLayout, 'text');
            app.PhaseMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseMultiplierEditFieldValueChanged, true);
            app.PhaseMultiplierEditField.Value = '1';
            app.ReconstructionHologramsPanel = uipanel(app.ReconstructionControlLayout);
            app.ReconstructionHologramsListBoxLayout = uigridlayout(app.ReconstructionHologramsPanel);
            app.FilesListBox_3Label_3 = uilabel(app.ReconstructionHologramsListBoxLayout);
            app.FilesListBox_3Label_3.Text = 'Holograms';
            app.ReconstructionHologramsListBox = uilistbox(app.ReconstructionHologramsListBoxLayout);
            app.ReconstructionHologramsListBox.Items = {''};
            app.ReconstructionHologramsListBox.ValueChangedFcn = createCallbackFcn(app, @ReconstructionHologramsListBoxValueChanged, true);
            app.ReconstructionHologramsListBox.Value = {};
            app.ReconstructionDisplayLayout = uigridlayout(app.ReconstructionLayout);
            app.ReconstructionUIAxes = uiaxes(app.ReconstructionDisplayLayout);
            app.ReconstructionUIAxes.XTick = [];
            app.ReconstructionUIAxes.YTick = [];
            app.ReconstructionUIAxes.ZTick = [];
            app.ReconstructionDisplayButtonsLayout = uigridlayout(app.ReconstructionDisplayLayout);
            app.ReconstructionNextButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'push');
            app.ReconstructionNextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonValueChanged, true);
            app.ReconstructionNextButton.Text = '>';
            app.ReconstructionPreviousButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'push');
            app.ReconstructionPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonValueChanged, true);
            app.ReconstructionPreviousButton.Text = '<';
            app.ReconstructionPhaseButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'state');
            app.ReconstructionPhaseButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionPhaseButtonValueChanged, true);
            app.ReconstructionPhaseButton.Text = 'Phase';
            app.ReconstructionAmplitudeButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'state');
            app.ReconstructionAmplitudeButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionAmplitudeButtonValueChanged, true);
            app.ReconstructionAmplitudeButton.Text = 'Amplitude';
            app.ReconstructionAmplitudeButton.Value = true;
            app.ReconstructionDarkFieldButton = uibutton(app.ReconstructionDisplayButtonsLayout, 'state');
            app.ReconstructionDarkFieldButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionDarkFieldButtonValueChanged, true);
            app.ReconstructionDarkFieldButton.Text = 'Dark Field';
            app.ReconstructionGrahpSettingsLayout = uigridlayout(app.ReconstructionDisplayLayout);
            app.ReconstructionRangeLabel = uilabel(app.ReconstructionGrahpSettingsLayout);
            app.ReconstructionRangeLabel.Text = 'Value range:';
            app.ReconstructionRangeStartEditField = uieditfield(app.ReconstructionGrahpSettingsLayout, 'text');
            app.ReconstructionRangeStartEditField.ValueChangedFcn = createCallbackFcn(app, @RangeStartEditFieldValueChanged, true);
            app.ReconstructionRangeStartEditField.Value = '0';
            app.Label_6 = uilabel(app.ReconstructionGrahpSettingsLayout);
            app.Label_6.Text = '-';
            app.ReconstructionRangeEndEditField = uieditfield(app.ReconstructionGrahpSettingsLayout, 'text');
            app.ReconstructionRangeEndEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEndEditFieldValueChanged, true);
            app.ReconstructionRangeEndEditField.Value = '255';
            app.ResultsTab = uitab(app.NavigationTab);
            app.ResultsTab.Title = 'Results';
            app.ResultsLayout = uigridlayout(app.ResultsTab);
            app.SaveSettingsLayout = uigridlayout(app.ResultsLayout);
            app.ExportSettingsLayout = uigridlayout(app.SaveSettingsLayout);
            app.ExportdataButton = uibutton(app.ExportSettingsLayout, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.ExportsettingsLabel = uilabel(app.ExportSettingsLayout);
            app.ExportsettingsLabel.Text = 'Export settings';
            app.AmplitudeValueRangeLayout = uigridlayout(app.ExportSettingsLayout);
            app.AmplitudeValueRangeLabel_3 = uilabel(app.AmplitudeValueRangeLayout);
            app.AmplitudeValueRangeLabel_3.Text = 'Amplitude Value Range';
            app.AmplitudeEndValue = uieditfield(app.AmplitudeValueRangeLayout, 'text');
            app.AmplitudeEndValue.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEndValueValueChanged, true);
            app.AmplitudeEndValue.Value = '255';
            app.AmplitudeSeparator = uilabel(app.AmplitudeValueRangeLayout);
            app.AmplitudeSeparator.Text = '-';
            app.AmplitudeStartValue = uieditfield(app.AmplitudeValueRangeLayout, 'text');
            app.AmplitudeStartValue.ValueChangedFcn = createCallbackFcn(app, @AmplitudeStartValueValueChanged, true);
            app.AmplitudeStartValue.Value = '0';
            app.PhaseValueRangeLayout = uigridlayout(app.ExportSettingsLayout);
            app.PhaseEndValue = uieditfield(app.PhaseValueRangeLayout, 'text');
            app.PhaseEndValue.ValueChangedFcn = createCallbackFcn(app, @PhaseEndValueValueChanged, true);
            app.PhaseEndValue.Value = '3.14';
            app.PhaseSeparator = uilabel(app.PhaseValueRangeLayout);
            app.PhaseSeparator.Text = '-';
            app.PhaseValueRangeEditFieldLabel = uilabel(app.PhaseValueRangeLayout);
            app.PhaseValueRangeEditFieldLabel.Text = 'Phase Value Range';
            app.PhaseStartValue = uieditfield(app.PhaseValueRangeLayout, 'text');
            app.PhaseStartValue.ValueChangedFcn = createCallbackFcn(app, @PhaseStartValueValueChanged, true);
            app.PhaseStartValue.Value = '-3.14';
            app.ExportDarkFieldReconstructionCheckBox = uicheckbox(app.ExportSettingsLayout);
            app.ExportDarkFieldReconstructionCheckBox.Text = 'Export Dark Field Reconstruction ';
            app.ReconstructionPhaseMultiplierLayout = uigridlayout(app.ExportSettingsLayout);
            app.ReconstructionPhaseMultiplierEditFieldLabel = uilabel(app.ReconstructionPhaseMultiplierLayout);
            app.ReconstructionPhaseMultiplierEditFieldLabel.Text = 'Reconstruction Phase Multiplier';
            app.ReconstructionPhaseMultiplierEditField = uieditfield(app.ReconstructionPhaseMultiplierLayout, 'text');
            app.ReconstructionPhaseMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @ReconstructionPhaseMultiplierEditFieldValueChanged, true);
            app.ReconstructionPhaseMultiplierEditField.Value = '1';
            app.ExportModeDropDownLayout = uigridlayout(app.ExportSettingsLayout);
            app.ExportModeDropDownLabel = uilabel(app.ExportModeDropDownLayout);
            app.ExportModeDropDownLabel.Text = 'Export Mode';
            app.ExportModeDropDown = uidropdown(app.ExportModeDropDownLayout);
            app.ExportModeDropDown.Items = {'Amplitude', 'Phase', 'Both'};
            app.ExportModeDropDown.Value = 'Both';
            app.DropDownLayout_3 = uigridlayout(app.ExportSettingsLayout);
            app.ExportedFormatsEditFieldLabel = uilabel(app.DropDownLayout_3);
            app.ExportedFormatsEditFieldLabel.Text = 'Exported Formats';
            app.ExportedFormatsEditField = uieditfield(app.DropDownLayout_3, 'text');
            app.ExportedFormatsEditField.Editable = 'off';
            app.ExportedFormatsEditField.Value = '.tif;';
            app.FormatContolLayout = uigridlayout(app.ExportSettingsLayout);
            app.FormatDropDownLabel = uilabel(app.FormatContolLayout);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.FormatContolLayout);
            app.FormatDropDown.Items = {'.tif', '.mat', '.fig'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = '.tif';
            app.AddFormatButton = uibutton(app.FormatContolLayout, 'push');
            app.AddFormatButton.ButtonPushedFcn = createCallbackFcn(app, @AddFormatButtonPushed, true);
            app.AddFormatButton.Enable = 'off';
            app.AddFormatButton.Text = {'Add '; 'Format'};
            app.DeleteFormatButton = uibutton(app.FormatContolLayout, 'push');
            app.DeleteFormatButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFormatButtonPushed, true);
            app.DeleteFormatButton.Text = {'Delete'; 'Format'};
            app.ExportreconstructiondataCheckBox = uicheckbox(app.ExportSettingsLayout);
            app.ExportreconstructiondataCheckBox.Text = 'Export reconstruction data';
            app.SaveSelectorLayout = uigridlayout(app.ResultsLayout);
            app.AvailableHologramsListBoxLabel = uilabel(app.SaveSelectorLayout);
            app.AvailableHologramsListBoxLabel.Text = 'Available Holograms';
            app.ExportedHologramsListBox = uilistbox(app.SaveSelectorLayout);
            app.ExportedHologramsListBox.Items = {};
            app.ExportedHologramsListBox.Value = {};
            app.ExportedHologramsListBoxLabel = uilabel(app.SaveSelectorLayout);
            app.ExportedHologramsListBoxLabel.Text = 'Exported Holograms';
            app.ResultsControlButtonsLayout = uigridlayout(app.SaveSelectorLayout);
            app.MoveAllRight = uibutton(app.ResultsControlButtonsLayout, 'push');
            app.MoveAllRight.ButtonPushedFcn = createCallbackFcn(app, @MoveAllRightButtonPushed, true);
            app.MoveAllRight.Text = '>>';
            app.MoveSingleRight = uibutton(app.ResultsControlButtonsLayout, 'push');
            app.MoveSingleRight.ButtonPushedFcn = createCallbackFcn(app, @MoveSingleRightButtonPushed, true);
            app.MoveSingleRight.Text = '>';
            app.MoveSingleLeft = uibutton(app.ResultsControlButtonsLayout, 'push');
            app.MoveSingleLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveSingleLeftButtonPushed, true);
            app.MoveSingleLeft.Text = '<';
            app.MoveAllLeft = uibutton(app.ResultsControlButtonsLayout, 'push');
            app.MoveAllLeft.ButtonPushedFcn = createCallbackFcn(app, @MoveAllLeftButtonPushed, true);
            app.MoveAllLeft.Text = '<<';
            app.AvailableHologramsListBox = uilistbox(app.SaveSelectorLayout);
            app.AvailableHologramsListBox.Items = {};
            app.AvailableHologramsListBox.Value = {};
            app.LogoNavLayout = uigridlayout(app.SideLayout);
            app.Image = uiimage(app.LogoNavLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'data', 'Najlepsze_logo.png');
            app.NavigationLayout = uigridlayout(app.LogoNavLayout);
            app.GPUProcessingCheckBox = uicheckbox(app.NavigationLayout);
            app.GPUProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @GPUProcessingCheckBoxValueChanged, true);
            app.GPUProcessingCheckBox.Text = 'GPU Processing';
            app.ExportResultsButton = uibutton(app.NavigationLayout, 'state');
            app.ExportResultsButton.ValueChangedFcn = createCallbackFcn(app, @ExportResultsButtonValueChanged, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.ReconstructionButton = uibutton(app.NavigationLayout, 'state');
            app.ReconstructionButton.ValueChangedFcn = createCallbackFcn(app, @ReconstructionButtonValueChanged, true);
            app.ReconstructionButton.Enable = 'off';
            app.ReconstructionButton.Text = 'Reconstruction';
            app.ShiftButton = uibutton(app.NavigationLayout, 'state');
            app.ShiftButton.ValueChangedFcn = createCallbackFcn(app, @ShiftButtonValueChanged, true);
            app.ShiftButton.Enable = 'off';
            app.ShiftButton.Text = 'Shift';
            app.FocusButton = uibutton(app.NavigationLayout, 'state');
            app.FocusButton.ValueChangedFcn = createCallbackFcn(app, @FocusButtonValueChanged, true);
            app.FocusButton.Enable = 'off';
            app.FocusButton.Text = 'Focus';
            app.LoadDataButton = uibutton(app.NavigationLayout, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.LoadDataButton.Value = true;
            app.GridLayout = uigridlayout(app.NavigationLayout);
            app.SaveSessionButton = uibutton(app.GridLayout, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Enable = 'off';
            app.SaveSessionButton.Text = 'Save Session';
            app.ApplicationStateBar = uigridlayout(app.BackLayout);
            app.StatusLabel = uilabel(app.ApplicationStateBar);
            app.StatusLabel.Text = 'Status:';
            app.ApplicationStateLabel = uilabel(app.ApplicationStateBar);
            app.ApplicationStateLabel.Text = 'Idle';
            app.HelperPanel = uipanel(app.HoLLoAppUIFigure);
            app.HelperPanel.Enable = 'off';
            app.HelperPanel.Visible = 'off';
            % Show the figure after all components are created
            app.HoLLoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoLLoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoLLoAppUIFigure)
        end
    end
end