classdef HoloPrep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        MainLayout                   matlab.ui.container.GridLayout
        LoadHologramsPanelLayout     matlab.ui.container.GridLayout
        ListBoxLayout                matlab.ui.container.GridLayout
        DataSourceListBox            matlab.ui.control.ListBox
        HologramsLabel               matlab.ui.control.Label
        PreviewLayout                matlab.ui.container.GridLayout
        LoadHologramsDisplayButtonsLayout  matlab.ui.container.GridLayout
        LoadHologramsNextButton      matlab.ui.control.Button
        LoadHologramsPreviousButton  matlab.ui.control.Button
        LoadHologramsUIAxes          matlab.ui.control.UIAxes
        ConfigureLayout              matlab.ui.container.GridLayout
        NumericLayout                matlab.ui.container.GridLayout
        PaddinglayersEditField       matlab.ui.control.NumericEditField
        PaddinglayersEditFieldLabel  matlab.ui.control.Label
        DropDownLayout               matlab.ui.container.GridLayout
        LoadedLayersDropDown         matlab.ui.control.DropDown
        LoadedLayersDropDownLabel    matlab.ui.control.Label
        AverageSettingsLayout        matlab.ui.container.GridLayout
        AveragesettingsLabel         matlab.ui.control.Label
        NumericLayout2               matlab.ui.container.GridLayout
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        AveragingCheckBox            matlab.ui.control.CheckBox
        ConfigureLabel               matlab.ui.control.Label
        AddDeleteControls            matlab.ui.container.GridLayout
        AddButton                    matlab.ui.control.Button
        AddAllButton                 matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        DeleteAllButton              matlab.ui.control.Button
        LoadedHologramsLayout        matlab.ui.container.GridLayout
        ListBoxLayout2               matlab.ui.container.GridLayout
        LoadedHologamsListBox        matlab.ui.control.ListBox
        HologramsLabel_2             matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Files
        SelectedHologram  (:,:,:)                               = []
        LoadedHolograms   (:,:,:)                               = []
        hologramLayerIndex                                      = 1
        
        callerApp
    end
    
    methods (Access = private)
        
        function LoadFilesToDataSourceListBox(app)
            filesNumber = length(app.Files);
            filesNames = cell(1, filesNumber);
            for i = 1:filesNumber
                filesNames{i} = app.Files(i).name;
            end
            app.DataSourceListBox.Items = filesNames;
            app.DataSourceListBox.ItemsData = 1:filesNumber;
        end

        function hologram = LoadHologramFromFiles(app, files, index)
            fileName = files(index).name;
            filePath = fullfile(files(index).folder, files(index).name);
            
            if(contains(fileName, ".mat"))
                hologram = cell2mat(struct2cell(load(filePath)));
            elseif(contains(fileName, ".png"))
                hologram = imread(filePath);
            elseif(contains(fileName, ".tif"))
                hologram = imread(filePath);
            elseif(contains(fileName, ".bmp"))
                hologram = imread(filePath);
            end

            if(size(hologram,3) == 1)
                app.LoadHologramsNextButton.Enable = "off";
                app.LoadHologramsPreviousButton.Enable = "off";
            else
                app.LoadHologramsNextButton.Enable = "on";
                app.LoadHologramsPreviousButton.Enable = "on";
            end
        end

        function HandleHologramChange(app, index)
            app.SelectedHologram = app.LoadHologramFromFiles(app.Files, index);

            if(size(app.SelectedHologram,3)==1)
                app.LoadedLayersDropDown.Value = '1';
                app.LoadedLayersDropDown.Enable = "off";
            elseif(size(app.SelectedHologram,3)==3)
                app.LoadedLayersDropDown.Items = {'1', '2', '3', '[1,2]', '[2,3]', '[1,2,3]'};
                app.LoadedLayersDropDown.ItemsData = {'1', '2', '3', '1:2', '2:3', '1:3'};
                app.LoadedLayersDropDown.Enable = "on";
            else
                app.LoadedLayersDropDown.Items = {'1', 'all'};
                app.LoadedLayersDropDown.ItemsData = {'1', ':'};
                app.LoadedLayersDropDown.Value = ':';
            end
            
            averagingOn = app.AveragingCheckBox.Value;
            
            if(averagingOn)
                sigma = app.SigmaEditField.Value;
                app.SelectedHologram = app.AverageHologram(app.SelectedHologram, sigma);
            end

            paddingValue = app.PaddinglayersEditField.Value;

            if(paddingValue > 0)
                app.SelectedHologram = app.PadHologram(app.SelectedHologram, paddingValue);
            end
            
        end

        function padedHologram = PadHologram(app, hologram, paddingValue)
            padedHologram = padarray(hologram,[paddingValue, paddingValue], "replicate", "both");
        end

        function averagedHologram = AverageHologram(app, hologram, sigma)
            if(sigma < inf)
                averagedHologram = hologram./imgaussfilt(hologram, sigma);
            else
                averagedHologram = hologram/mean2(hologram);
            end
        end

        function DisplaySelectedHologram(app, hologramLayerIndex)
            index = app.DataSourceListBox.Value;

            if(size(app.SelectedHologram,3) == 1)
                hologram = double(app.SelectedHologram);
                hologramTitle = app.Files(index).name + " 1/1";
                hologramTitle = "\color{white}" + hologramTitle;
                title(app.LoadHologramsUIAxes, hologramTitle);
            else
                hologram = double(app.SelectedHologram(:,:,hologramLayerIndex));
                hologramTitle = app.Files(index).name + " " + num2str(hologramLayerIndex) + "/" + num2str(size(app.SelectedHologram,3));
                hologramTitle = "\color{white}" + hologramTitle;
                title(app.LoadHologramsUIAxes, hologramTitle);
            end
            
            averagingOn = app.AveragingCheckBox.Value;
            
            if(averagingOn)
                range = [0 2];
            else
                range = [0,255];
            end

            %Fix axes limits
            app.LoadHologramsUIAxes.XLim(2) = size(hologram,2);
            app.LoadHologramsUIAxes.YLim(2) = size(hologram,1);

            imshow(hologram, range, "Parent", app.LoadHologramsUIAxes);
        end

        function AddHologram(app, index)
            hologram = app.LoadHologramFromFiles(app.Files, index);
            layerString = app.LoadedLayersDropDown.Value;
            layers = LayerStringToIndexArray(app, layerString);
            layerCount = length(layers);

            averagingOn = app.AveragingCheckBox.Value;
        
            if(averagingOn)
                sigma = app.SigmaEditField.Value;
                hologram = app.AverageHologram(hologram, sigma);
            end

            paddingValue = app.PaddinglayersEditField.Value;

            if(paddingValue > 0)
                hologram = app.PadHologram(hologram, paddingValue);
            end

            if(size(hologram,3) == 1)
                if(isempty(app.LoadedHolograms))
                    app.LoadedHolograms(:,:,end) = double(hologram);
                else
                    app.LoadedHolograms(:,:,end+1) = double(hologram);
                end
            else
                if(isempty(app.LoadedHolograms))
                    app.LoadedHolograms(:,:,1:layerCount) = double(hologram(:,:, layers));
                else
                    app.LoadedHolograms(:,:,end+1:(end+layerCount)) = double(hologram(:,:, layers));
                end
            end

            name = strsplit(app.Files(index).name,".");
            names = cell(1,layerCount);
            
            if(size(hologram,3) == 1)
                names{1} = name{1};
            else
                for layerArrayIndex = 1:length(layers)
                    names{layerArrayIndex} = [name{1} num2str(layers(layerArrayIndex))];
                end
            end
            
            for layerArrayIndex = 1:length(layers)
                app.LoadedHologamsListBox.Items{end + 1} = names{layerArrayIndex};
                app.LoadedHologamsListBox.ItemsData(end+1) = length(app.LoadedHologamsListBox.ItemsData) + 1;
            end
        end

        function indexArray = LayerStringToIndexArray(app, layerString)
            if(contains(layerString,":"))
                if(isscalar(layerString))
                    startLayer = 1;
                    endLayer = size(app.SelectedHologram,3);
    
                    indexArray = startLayer:endLayer;
                else
                layers = strsplit(layerString, ":");

                startLayer = str2double(layers(1));
                endLayer = str2double(layers(2));

                indexArray = startLayer:endLayer;
                end
            else
                indexArray = str2double(layerString);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            app.callerApp = callerApp;
            app.Files = app.callerApp.Files;
            
            app.LoadFilesToDataSourceListBox();

            app.DataSourceListBox.Value = 1;
            index = app.DataSourceListBox.Value;
            
            app.SelectedHologram = app.LoadHologramFromFiles(app.Files, index);

            if(size(app.SelectedHologram,3)==1)
                app.LoadedLayersDropDown.Value = "1";
                app.LoadedLayersDropDown.Enable = "off";
            elseif(size(app.SelectedHologram,3)==3)
                app.LoadedLayersDropDown.Items = {'1', '2', '3', '[1,2]', '[2,3]', '[1,2,3]'};
                app.LoadedLayersDropDown.ItemsData = {'1', '2', '3', '1:2', '2:3', '1:3'};
                app.LoadedLayersDropDown.Enable = "on";
            else
                app.LoadedLayersDropDown.Items = {'1', 'all'};
                app.LoadedLayersDropDown.ItemsData = {'1', ':'};
                app.LoadedLayersDropDown.Value = ':';
            end

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: LoadHologramsPreviousButton
        function LoadHologramsPreviousButtonPushed(app, event)
            app.hologramLayerIndex = app.hologramLayerIndex - 1;

            if(app.hologramLayerIndex<1)
                app.hologramLayerIndex = size(app.SelectedHologram,3);
            end

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: LoadHologramsNextButton
        function LoadHologramsNextButtonPushed(app, event)
            app.hologramLayerIndex = app.hologramLayerIndex + 1;
            if(app.hologramLayerIndex>size(app.SelectedHologram,3))
                app.hologramLayerIndex = 1;
            end
            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Value changed function: AveragingCheckBox, DataSourceListBox, 
        % ...and 2 other components
        function HologramChanged(app, event)
            index = app.DataSourceListBox.Value;
            
            app.HandleHologramChange(index);

            app.DisplaySelectedHologram(app.hologramLayerIndex);
        end

        % Button pushed function: AddButton
        function AddButtonButtonPushed(app, event)
            index = app.DataSourceListBox.Value;
            
            app.AddHologram(index);
        end

        % Button pushed function: AddAllButton
        function AddAllButtonPushed(app, event)
            app.LoadedHolograms = [];
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.ItemsData = [];

            for index = 1:length(app.Files)
                app.AddHologram(index);
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.LoadedHolograms = [];
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.ItemsData = [];
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            index = app.LoadedHologamsListBox.Value;

            app.LoadedHolograms(:,:, index) = [];
            app.LoadedHologamsListBox.Items(index) = [];
            app.LoadedHologamsListBox.ItemsData(index:end) = app.LoadedHologamsListBox.ItemsData(index:end) - 1;
            app.LoadedHologamsListBox.ItemsData(index) = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.callerApp.HologramsRaw = app.LoadedHolograms;
            app.callerApp.HologramsRawNames = app.LoadedHologamsListBox.Items;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callerApp.PopupStatus = -1;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainLayout = uigridlayout(app.UIFigure);
            app.LoadHologramsPanelLayout = uigridlayout(app.MainLayout);
            app.LoadedHologramsLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.LoadButton = uibutton(app.LoadedHologramsLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ListBoxLayout2 = uigridlayout(app.LoadedHologramsLayout);
            app.HologramsLabel_2 = uilabel(app.ListBoxLayout2);
            app.HologramsLabel_2.Text = 'Loaded Holograms';
            app.LoadedHologamsListBox = uilistbox(app.ListBoxLayout2);
            app.LoadedHologamsListBox.Items = {};
            app.LoadedHologamsListBox.Value = {};
            app.AddDeleteControls = uigridlayout(app.LoadHologramsPanelLayout);
            app.DeleteAllButton = uibutton(app.AddDeleteControls, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = {'XX'; 'Delete '; 'all'};
            app.DeleteButton = uibutton(app.AddDeleteControls, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = {'X'; 'Delete'};
            app.AddAllButton = uibutton(app.AddDeleteControls, 'push');
            app.AddAllButton.ButtonPushedFcn = createCallbackFcn(app, @AddAllButtonPushed, true);
            app.AddAllButton.Text = {'++'; 'Add '; 'all'};
            app.AddButton = uibutton(app.AddDeleteControls, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonButtonPushed, true);
            app.AddButton.Text = {'+'; 'Add'};
            app.ConfigureLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.ConfigureLabel = uilabel(app.ConfigureLayout);
            app.ConfigureLabel.Text = 'Configure';
            app.AverageSettingsLayout = uigridlayout(app.ConfigureLayout);
            app.AveragingCheckBox = uicheckbox(app.AverageSettingsLayout);
            app.AveragingCheckBox.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.AveragingCheckBox.Text = 'Averaging off';
            app.NumericLayout2 = uigridlayout(app.AverageSettingsLayout);
            app.SigmaEditFieldLabel = uilabel(app.NumericLayout2);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.NumericLayout2, 'numeric');
            app.SigmaEditField.Limits = [0.1 Inf];
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.SigmaEditField.Value = 10;
            app.AveragesettingsLabel = uilabel(app.AverageSettingsLayout);
            app.AveragesettingsLabel.Text = 'Average settings';
            app.DropDownLayout = uigridlayout(app.ConfigureLayout);
            app.LoadedLayersDropDownLabel = uilabel(app.DropDownLayout);
            app.LoadedLayersDropDownLabel.Text = 'Loaded Layers';
            app.LoadedLayersDropDown = uidropdown(app.DropDownLayout);
            app.LoadedLayersDropDown.Items = {'1', '2', '3', '[1,2]', '[2,3]', '[1,2,3]'};
            app.LoadedLayersDropDown.ItemsData = {'1', '2', '3', '1:2', '2:3', '1:3'};
            app.LoadedLayersDropDown.Value = '1';
            app.NumericLayout = uigridlayout(app.ConfigureLayout);
            app.PaddinglayersEditFieldLabel = uilabel(app.NumericLayout);
            app.PaddinglayersEditFieldLabel.Text = 'Padding layers';
            app.PaddinglayersEditField = uieditfield(app.NumericLayout, 'numeric');
            app.PaddinglayersEditField.Limits = [0 Inf];
            app.PaddinglayersEditField.RoundFractionalValues = 'on';
            app.PaddinglayersEditField.ValueDisplayFormat = '%.0f';
            app.PaddinglayersEditField.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.PreviewLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.LoadHologramsUIAxes = uiaxes(app.PreviewLayout);
            app.LoadHologramsUIAxes.XTick = [];
            app.LoadHologramsUIAxes.YTick = [];
            app.LoadHologramsUIAxes.ZTick = [];
            app.LoadHologramsDisplayButtonsLayout = uigridlayout(app.PreviewLayout);
            app.LoadHologramsPreviousButton = uibutton(app.LoadHologramsDisplayButtonsLayout, 'push');
            app.LoadHologramsPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsPreviousButtonPushed, true);
            app.LoadHologramsPreviousButton.Text = '<';
            app.LoadHologramsNextButton = uibutton(app.LoadHologramsDisplayButtonsLayout, 'push');
            app.LoadHologramsNextButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHologramsNextButtonPushed, true);
            app.LoadHologramsNextButton.Text = '>';
            app.ListBoxLayout = uigridlayout(app.LoadHologramsPanelLayout);
            app.HologramsLabel = uilabel(app.ListBoxLayout);
            app.HologramsLabel.Text = 'Data source';
            app.DataSourceListBox = uilistbox(app.ListBoxLayout);
            app.DataSourceListBox.Items = {};
            app.DataSourceListBox.ValueChangedFcn = createCallbackFcn(app, @HologramChanged, true);
            app.DataSourceListBox.Value = {};
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloPrep(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end